classdef WISTFUL_geotherm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LoadGeothermFileButton          matlab.ui.control.Button
        PropertyLabel                   matlab.ui.control.Label
        rightxAxisMax                   matlab.ui.control.NumericEditField
        rightxAxisMin                   matlab.ui.control.NumericEditField
        TempLabel                       matlab.ui.control.Label
        MaxLabel                        matlab.ui.control.Label
        MinLabel                        matlab.ui.control.Label
        xAxisMax                        matlab.ui.control.NumericEditField
        xAxisMin                        matlab.ui.control.NumericEditField
        SetAxesCheckBox                 matlab.ui.control.CheckBox
        ChoiceDropDown                  matlab.ui.control.DropDown
        TPotLabel                       matlab.ui.control.Label
        MaxDepthkmEditField             matlab.ui.control.NumericEditField
        MaxDepthkmEditFieldLabel        matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        SurfaceQEditField               matlab.ui.control.NumericEditField
        surfaceQEditFieldLabel          matlab.ui.control.Label
        HeatProductivityCrustEditField  matlab.ui.control.NumericEditField
        HeatProductivityCrustLabel      matlab.ui.control.Label
        HeatProductivityEditField       matlab.ui.control.NumericEditField
        HeatProductivityofMantleWm3Label  matlab.ui.control.Label
        PlateThicknessEditField         matlab.ui.control.NumericEditField
        PlateThicknesskmEditFieldLabel  matlab.ui.control.Label
        AdiabatEditField                matlab.ui.control.NumericEditField
        AdiabatCkmLabel                 matlab.ui.control.Label
        TpotEditField                   matlab.ui.control.NumericEditField
        DataToPlotDropDown              matlab.ui.control.DropDown
        DataToPlotDropDownLabel         matlab.ui.control.Label
        kappaEditFieldLabel             matlab.ui.control.Label
        kappaEditField                  matlab.ui.control.NumericEditField
        AgeMyrEditField                 matlab.ui.control.NumericEditField
        AgeMyrEditFieldLabel            matlab.ui.control.Label
        UseChapmanCheckBox              matlab.ui.control.CheckBox
        RockTypeToPlotDropDown          matlab.ui.control.DropDown
        RockTypeToPlotDropDownLabel     matlab.ui.control.Label
        GeothermButtonGroup             matlab.ui.container.ButtonGroup
        InputGeothermButton             matlab.ui.control.RadioButton
        ContinentalButton               matlab.ui.control.RadioButton
        PlateModelButton                matlab.ui.control.RadioButton
        HalfSpaceCoolingModelButton     matlab.ui.control.RadioButton
        DefaultfileisloadedLabel        matlab.ui.control.Label
        LoadSpeedFileButton             matlab.ui.control.Button
        ExportDatatoWorkspaceButton     matlab.ui.control.Button
        MaximumLabel                    matlab.ui.control.Label
        MinimumLabel                    matlab.ui.control.Label
        MaximumEditField                matlab.ui.control.NumericEditField
        MinimumEditField                matlab.ui.control.NumericEditField
        AddaCompositionalConstraintCheckBox  matlab.ui.control.CheckBox
        CompositionalDropDown           matlab.ui.control.DropDown
        UIAxes_right                    matlab.ui.control.UIAxes
        UIAxes_left                     matlab.ui.control.UIAxes
    end


    properties (Access = public)
        compositions=load('WISTFUL_compositions_clean.mat'); % specify the path to mat file
        density=load('WISTFUL_densities_clean.mat'); % specify the path to mat file
        %modes=load('WISTFUL_parsed_modes_clean.mat'); % specify the path to mat file
        rocktype=load('WISTFUL_rockType.mat'); % specify the path to mat file
        moduli=load('WISTFUL_speeds_moduli_clean.mat'); % specify the path to mat file
        z=[];
        geotherm=[];
        propertyAlongGeotherm=[];
        data1=[];
        constraints=[];
        pressure=[];
        tIndices=[];
        pIndices=[];
        allProperties=[];
        meanProperty=[];
        stdProperty=[];
        inputZ=[];
        inputP=[];
        inputGeotherm=[];
        hasLoadedGeotherm=false;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TPotLabel.Text=['Potential Temperature [' char(176) 'C]'];
            app.AdiabatCkmLabel.Text = ['Adiabat [' char(176) 'C/km]'];
            app.SurfaceQEditField.Value=round(3.3*app.TpotEditField.Value/sqrt(pi*app.kappaEditField.Value*1e6*365.25*24*60*60*app.AgeMyrEditField.Value)*1e3);
            app.UIAxes_right.YLimMode='manual';
            app.UIAxes_left.YLimMode='manual';
            app.UIAxes_left.YLim=[0 app.MaxDepthkmEditField.Value];
            app.UIAxes_right.YLim=[0 app.MaxDepthkmEditField.Value];

        end

        % Button pushed function: LoadSpeedFileButton
        function LoadSpeedFileButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            if isequal(file,0)
                %   disp('User selected Cancel')
            else
                app.moduli=load(fullfile(path,file));
                app.DefaultfileisloadedLabel.Text = [file ' is now loaded.'];
            end
            figure(app.UIFigure);
        end

        % Value changed function: UseChapmanCheckBox
        function UseChapmanCheckBoxValueChanged(app, event)
            value = app.UseChapmanCheckBox.Value;
            app.HeatProductivityCrustEditField.Editable=~value;
            if value
                app.HeatProductivityCrustEditField.Value=.4*app.SurfaceQEditField.Value/app.PlateThicknessEditField.Value;
            end
        end

        % Selection changed function: GeothermButtonGroup
        function GeothermButtonGroupSelectionChanged(app, event)
            selectedButton = app.GeothermButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text, 'Half-Space Cooling Model')
                app.AgeMyrEditField.Editable=true;
                app.SurfaceQEditField.Editable=false;
                app.SurfaceQEditField.Value=round(3.3*app.TpotEditField.Value/sqrt(pi*app.kappaEditField.Value*1e6*365.25*24*60*60*app.AgeMyrEditField.Value)*1e3);
                app.PlateThicknessEditField.Visible=false;
                app.PlateThicknesskmEditFieldLabel.Visible=false;
                app.HeatProductivityEditField.Visible=false;
                app.HeatProductivityCrustEditField.Visible=false;
                app.HeatProductivityCrustLabel.Visible=false;
                app.HeatProductivityofMantleWm3Label.Visible=false;
                app.UseChapmanCheckBox.Visible=false;
            elseif strcmp(selectedButton.Text, 'Plate Model')
                app.AgeMyrEditField.Editable=true;
                app.PlateThicknessEditField.Visible=true;
                app.SurfaceQEditField.Editable=false;
                app.PlateThicknesskmEditFieldLabel.Text = 'Plate Thickness [km]';
                app.PlateThicknesskmEditFieldLabel.Visible=true;
                app.PlateThicknessEditField.Value=100;
                app.SurfaceQEditField.Value=round(1e3*3.3*app.TpotEditField.Value/(app.PlateThicknessEditField.Value*1e3)...
                    *(1+2*(exp(-1*pi^2*app.kappaEditField.Value*1e6*365.25*24*60*60*app.AgeMyrEditField.Value/(app.PlateThicknessEditField.Value*1e3)^2)...
                    +exp(-4*pi^2*app.kappaEditField.Value*1e6*365.25*24*60*60*app.AgeMyrEditField.Value/(app.PlateThicknessEditField.Value*1e3)^2))));
                app.HeatProductivityEditField.Visible=false;
                app.HeatProductivityCrustEditField.Visible=false;
                app.HeatProductivityCrustLabel.Visible=false;
                app.HeatProductivityofMantleWm3Label.Visible=false;
                app.UseChapmanCheckBox.Visible=false;
            elseif strcmp(selectedButton.Text, 'Continental')
                app.AgeMyrEditField.Editable=false;
                app.PlateThicknessEditField.Visible=true;
                app.PlateThicknesskmEditFieldLabel.Text = 'Crustal Thickness [km]';
                app.PlateThicknesskmEditFieldLabel.Visible=true;
                app.PlateThicknessEditField.Value=35;
                app.HeatProductivityEditField.Visible=true;
                app.HeatProductivityCrustEditField.Visible=true;
                app.HeatProductivityCrustLabel.Visible=true;
                app.HeatProductivityofMantleWm3Label.Visible=true;
                app.SurfaceQEditField.Editable=true;
                app.SurfaceQEditField.Value=60;
                app.UseChapmanCheckBox.Visible=true;
            elseif strcmp(selectedButton.Text, 'Input Geotherm')
                app.AgeMyrEditField.Editable=false;
                app.PlateThicknessEditField.Visible=false;
                app.PlateThicknesskmEditFieldLabel.Text = 'Crustal Thickness [km]';
                app.PlateThicknesskmEditFieldLabel.Visible=false;
                app.PlateThicknessEditField.Value=35;
                app.HeatProductivityEditField.Visible=false;
                app.HeatProductivityCrustEditField.Visible=false;
                app.HeatProductivityCrustLabel.Visible=false;
                app.HeatProductivityofMantleWm3Label.Visible=false;
                app.UseChapmanCheckBox.Visible=false;
            end
            value2=~app.InputGeothermButton.Value;
            app.SurfaceQEditField.Visible=value2;
            app.AgeMyrEditField.Visible=value2;
            app.AgeMyrEditFieldLabel.Visible=value2;
            app.surfaceQEditFieldLabel.Visible=value2;
            app.kappaEditField.Visible=value2;
            app.kappaEditFieldLabel.Visible=value2;
            app.TPotLabel.Visible=value2;
            app.TpotEditField.Visible=value2;
            app.AdiabatCkmLabel.Visible=value2;
            app.AdiabatEditField.Visible=value2;
        end

        % Value changed function: 
        % AddaCompositionalConstraintCheckBox
        function AddaCompositionalConstraintCheckBoxValueChanged(app, event)
            value = app.AddaCompositionalConstraintCheckBox.Value;
            app.CompositionalDropDown.Visible=value;
            app.MaximumEditField.Visible=value;
            app.MinimumEditField.Visible=value;
            app.MinimumLabel.Visible = value;
            app.MaximumLabel.Visible = value;
        end

        % Value changed function: AgeMyrEditField
        function AgeMyrEditFieldValueChanged(app, event)
            if strcmp(app.GeothermButtonGroup.SelectedObject.Text,'Half-Space Cooling Model')
                app.SurfaceQEditField.Value=round(3.3*app.TpotEditField.Value/sqrt(pi*app.kappaEditField.Value*1e6*365.25*24*60*60*app.AgeMyrEditField.Value)*1e3);
            elseif strcmp(app.GeothermButtonGroup.SelectedObject.Text,'Plate Model')
                app.SurfaceQEditField.Value=round(1e3*3.3*app.TpotEditField.Value/(app.PlateThicknessEditField.Value*1e3)...
                    *(1+2*(exp(-1*pi^2*app.kappaEditField.Value*1e6*365.25*24*60*60*app.AgeMyrEditField.Value/(app.PlateThicknessEditField.Value*1e3)^2)...
                    +exp(-4*pi^2*app.kappaEditField.Value*1e6*365.25*24*60*60*app.AgeMyrEditField.Value/(app.PlateThicknessEditField.Value*1e3)^2))));
            end
        end

        % Value changed function: kappaEditField
        function kappaEditFieldValueChanged(app, event)
            if strcmp(app.GeothermButtonGroup.SelectedObject.Text,'Half-Space Cooling Model')
                app.SurfaceQEditField.Value=round(3.3*app.TpotEditField.Value/sqrt(pi*app.kappaEditField.Value*1e6*365.25*24*60*60*app.AgeMyrEditField.Value)*1e3);
            elseif strcmp(app.GeothermButtonGroup.SelectedObject.Text,'Plate Model')
                app.SurfaceQEditField.Value=round(1e3*3.3*app.TpotEditField.Value/(app.PlateThicknessEditField.Value*1e3)...
                    *(1+2*(exp(-1*pi^2*app.kappaEditField.Value*1e6*365.25*24*60*60*app.AgeMyrEditField.Value/(app.PlateThicknessEditField.Value*1e3)^2)...
                    +exp(-4*pi^2*app.kappaEditField.Value*1e6*365.25*24*60*60*app.AgeMyrEditField.Value/(app.PlateThicknessEditField.Value*1e3)^2))));
            end
        end

        % Value changed function: TpotEditField
        function TpotEditFieldValueChanged(app, event)
            if strcmp(app.GeothermButtonGroup.SelectedObject.Text,'Half-Space Cooling Model')
                app.SurfaceQEditField.Value=round(3.3*app.TpotEditField.Value/sqrt(pi*app.kappaEditField.Value*1e6*365.25*24*60*60*app.AgeMyrEditField.Value)*1e3);
            elseif strcmp(app.GeothermButtonGroup.SelectedObject.Text,'Plate Model')
                app.SurfaceQEditField.Value=round(1e3*3.3*app.TpotEditField.Value/(app.PlateThicknessEditField.Value*1e3)...
                    *(1+2*(exp(-1*pi^2*app.kappaEditField.Value*1e6*365.25*24*60*60*app.AgeMyrEditField.Value/(app.PlateThicknessEditField.Value*1e3)^2)...
                    +exp(-4*pi^2*app.kappaEditField.Value*1e6*365.25*24*60*60*app.AgeMyrEditField.Value/(app.PlateThicknessEditField.Value*1e3)^2))));
            end

        end

        % Value changed function: PlateThicknessEditField
        function PlateThicknessEditFieldValueChanged(app, event)
            if strcmp(app.GeothermButtonGroup.SelectedObject.Text,'Plate Model')
                app.SurfaceQEditField.Value=round(1e3*3.3*app.TpotEditField.Value/(app.PlateThicknessEditField.Value*1e3)...
                    *(1+2*(exp(-1*pi^2*app.kappaEditField.Value*1e6*365.25*24*60*60*app.AgeMyrEditField.Value/(app.PlateThicknessEditField.Value*1e3)^2)...
                    +exp(-4*pi^2*app.kappaEditField.Value*1e6*365.25*24*60*60*app.AgeMyrEditField.Value/(app.PlateThicknessEditField.Value*1e3)^2))));
            end
            if strcmp(app.GeothermButtonGroup.SelectedObject.Text,'Continental')
                if app.UseChapmanCheckBox.Value
                    app.HeatProductivityCrustEditField.Value=.4*app.SurfaceQEditField.Value/app.PlateThicknessEditField.Value;
                end
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            %this button does the following: 1) Calculates the geotherm
            % 2) finds the data along the geotherm
            % 3) plots data

            app.constraints=false(size(app.rocktype.rocktype));
            wantedRockType = app.RockTypeToPlotDropDown.Value;
            if strcmp(wantedRockType, 'All Peridotites')
                app.constraints=app.rocktype.isPeridotite;
            elseif strcmp(wantedRockType, 'Lherzolites')
                app.constraints=4==app.rocktype.rocktype;
            elseif strcmp(wantedRockType, 'Harzburgites')
                app.constraints=2==app.rocktype.rocktype;
            elseif strcmp(wantedRockType, 'Wehrlites')
                app.constraints=3==app.rocktype.rocktype;
            elseif strcmp(wantedRockType, 'Dunites')
                app.constraints=1==app.rocktype.rocktype;
            elseif strcmp(wantedRockType, 'All Pyroxenites')
                app.constraints=app.rocktype.isPyroxenite;
            elseif strcmp(wantedRockType, 'Olivine Webserites')
                app.constraints=5==app.rocktype.rocktype;
            elseif strcmp(wantedRockType, 'Websterites')
                app.constraints=6==app.rocktype.rocktype;
            elseif strcmp(wantedRockType, 'Clinopyroxenites')
                app.constraints=8==app.rocktype.rocktype;
            elseif strcmp(wantedRockType, 'Orthopyroxenites')
                app.constraints=7==app.rocktype.rocktype;
            elseif strcmp(wantedRockType, 'Olivine Orthopyroxenites')
                app.constraints=10==app.rocktype.rocktype;
            elseif strcmp(wantedRockType, 'Olivine Clinopyroxenites')
                app.constraints=11==app.rocktype.rocktype;
            end

            if app.AddaCompositionalConstraintCheckBox.Value%if constraint is checked
                if strcmp(app.CompositionalDropDown.Value,'Al2O3')
                    useData=app.compositions.Al2O3database';
                elseif strcmp(app.CompositionalDropDown.Value,'CaO')
                    useData=app.compositions.CaOdatabase';
                elseif strcmp(app.CompositionalDropDown.Value,'Cr2O3')
                    useData=app.compositions.Cr2O3database';
                elseif strcmp(app.CompositionalDropDown.Value,'FeO')
                    useData=app.compositions.FeOTdatabase';
                elseif strcmp(app.CompositionalDropDown.Value,'MgO')
                    useData=app.compositions.MgOdatabase';
                elseif strcmp(app.CompositionalDropDown.Value,'Mg #')
                    useData=app.compositions.mgnum*100';
                elseif strcmp(app.CompositionalDropDown.Value,'Na2O')
                    useData=app.compositions.Na2Odatabase';
                elseif strcmp(app.CompositionalDropDown.Value,'SiO2')
                    useData=app.compositions.SiO2database';
                elseif strcmp(app.CompositionalDropDown.Value,'Olivine')
                    useData=squeeze(app.modes.ol(pIndex,tIndex,:));
                elseif strcmp(app.CompositionalDropDown.Value,'Orthopyroxene')
                    useData=squeeze(app.modes.opx(pIndex,tIndex,:));
                elseif strcmp(app.CompositionalDropDown.Value,'Clinopyroxene')
                    useData=squeeze(app.modes.cpx(pIndex,tIndex,:));
                elseif strcmp(app.CompositionalDropDown.Value,'Garnet')
                    useData=squeeze(app.modes.gt(pIndex,tIndex,:));
                elseif strcmp(app.CompositionalDropDown.Value,'Spinel')
                    useData=squeeze(app.modes.sp(pIndex,tIndex,:));
                end
                %now add constraint
                app.constraints=app.constraints&useData<=app.MaximumEditField.Value&useData>=app.MinimumEditField.Value;
            end
            if strcmp(app.DataToPlotDropDown.Value,'Vp')
                app.data1=squeeze(app.moduli.vp);
                app.PropertyLabel.Text = 'Vp';
                app.UIAxes_right.XLabel.String = 'V_p [km s^{-1}]';
            elseif strcmp(app.DataToPlotDropDown.Value,'Vs')
                app.data1=squeeze(app.moduli.vs);
                app.UIAxes_right.XLabel.String = 'V_s [km s^{-1}]';
                app.PropertyLabel.Text = 'Vs';
            elseif strcmp(app.DataToPlotDropDown.Value,'Vp/Vs')
                app.data1=squeeze(app.moduli.vp./app.moduli.vs);
                app.UIAxes_right.XLabel.String = 'V_p/V_s';
                app.PropertyLabel.Text = 'Vp/Vs';
            elseif strcmp(app.DataToPlotDropDown.Value,'Density')
                app.data1=squeeze(app.density.rockDensity);
                app.UIAxes_right.XLabel.String = 'ρ [kg m^{-3}]';
                app.PropertyLabel.Text = 'Density';
            end
            %calculate pressure (use a dz of 2)
            app.z=0:2:app.MaxDepthkmEditField.Value;%km
            if app.ContinentalButton.Value
                app.pressure=zeros(size(app.z));
                app.pressure(app.z<app.PlateThicknessEditField.Value)=2800*app.z(app.z<app.PlateThicknessEditField.Value)*1e3*9.81;%Pa
                app.pressure(app.z>=app.PlateThicknessEditField.Value)=3300*app.z(app.z>=app.PlateThicknessEditField.Value)*1e3*9.81-500*app.PlateThicknessEditField.Value*1e3*9.81;
            else
                app.pressure=zeros(size(app.z));
                app.pressure(1:4)=2800*app.z(1:4)*1e3*9.81;%Pa
                app.pressure(5:end)=3300*app.z(5:end)*1e3*9.81-500*6e3*9.81;%Pa
            end
            %find pressure indices
            if app.InputGeothermButton.Value && app.hasLoadedGeotherm
                app.pressure=app.InputP;
                app.z=app.InputZ;
            end
            app.pIndices=zeros(size(app.pressure));
            for i=1:length(app.pressure)
                app.pIndices(i)=find(abs(app.pressure(i)-app.moduli.p)==min(abs(app.pressure(i)-app.moduli.p)), 1 );
            end
            %calculate geotherm
            Tpot=app.TpotEditField.Value;
            kappa=app.kappaEditField.Value;
            age=app.AgeMyrEditField.Value*1e6*365.25*24*60*60;
            thickness=round(app.PlateThicknessEditField.Value);
            app.geotherm=zeros(size(app.z));
            if app.ContinentalButton.Value
                dz=100;
                zfinal=app.z(end)*1e3;
                zNow=0;
                T=0;
                qt=app.SurfaceQEditField.Value*1e-3;
                A2=1e-6*app.HeatProductivityEditField.Value;
                A=1e-6*app.HeatProductivityCrustEditField.Value;
                jj=2;
                doNextLoop=true;
                for i=1:thickness*10
                  k=2.264-618.2./(273.1+T)+3.0*(355.6./(273.1+T)-0.3205);

                  T=T+qt./k*dz+A./(2*k)*dz^2;
                  qt=qt-A.*dz;
                  zNow=zNow+dz;
                  if sum(zNow/1e3==app.z)>0
                      Tadiabat=Tpot+zNow/1e3*app.AdiabatEditField.Value;
                      if T>Tadiabat
                          app.geotherm(jj:end)=Tpot+app.z(jj:end)*app.AdiabatEditField.Value;
                          doNextLoop=false;
                          break
                      end

                      app.geotherm(jj)=T;
                      jj=jj+1;
                  end
                end
                if doNextLoop
                    while zNow<=zfinal
                        k=2.264-618.2./(273.1+T)+3.0*(355.6./(273.1+T)-0.3205);
                        T=T+qt./k*dz+A./(2*k)*dz^2;
                        qt=qt-A2.*dz;
                        zNow=zNow+dz;
                        if sum(zNow/1e3==app.z)>0
                            Tadiabat=Tpot+zNow/1e3*app.AdiabatEditField.Value;
                            if T>=Tadiabat
                                app.geotherm(jj:end)=Tpot+app.z(jj:end)*app.AdiabatEditField.Value;
                                break
                            end
                            app.geotherm(jj)=T;
                            jj=jj+1;
                        end
                    end
                end
            elseif app.PlateModelButton.Value
                app.geotherm(app.z<thickness)=Tpot*(app.z(app.z<thickness)/thickness);
                for i=1:10
                    app.geotherm(app.z<thickness)=app.geotherm(app.z<thickness)+...
                        2/pi/i*Tpot*exp(-kappa*i^2*pi^2*age/(thickness*1e3)^2)*sin(i*pi*app.z(app.z<thickness)/thickness);
                end
                app.geotherm(app.z>=thickness)=Tpot;
                app.geotherm=app.geotherm+app.z*app.AdiabatEditField.Value;

            elseif app.HalfSpaceCoolingModelButton.Value
                app.geotherm=Tpot.*erf(app.z*1e3./sqrt(4*kappa*age));
                app.geotherm=app.geotherm+app.z*app.AdiabatEditField.Value;

            elseif app.InputGeothermButton.Value && app.hasLoadedGeotherm
                app.geotherm=app.inputGeotherm;
            end
            %add adiabat
            %find temperature indices
            app.tIndices=zeros(size(app.geotherm));
            for i=1:length(app.pressure)
                app.tIndices(i)=find(abs(app.geotherm(i)-app.moduli.t+273.1)==min(abs(app.geotherm(i)-app.moduli.t+273.1)), 1 );
            end
            plot(app.UIAxes_left,app.geotherm,app.z,'LineWidth',2,'Color','k')
            app.UIAxes_left.XLabel.String = ['Temperature [' char(176) 'C]'];
            app.UIAxes_left.YLabel.String = 'Depth [km]';
            app.allProperties=zeros(length(app.tIndices),sum(app.constraints));
            for i=1:length(app.tIndices)
                app.allProperties(i,:)=app.data1(app.pIndices(i),app.tIndices(i),app.constraints);
            end
            app.allProperties(app.allProperties==0)=NaN;
            app.meanProperty=nanmean(app.allProperties,2)';
            app.stdProperty=nanstd(app.allProperties');
            minProperty=min(app.allProperties');
            maxProperty=max(app.allProperties');
            if strcmp(app.ChoiceDropDown.Value, 'Plot Mean and Range')
               plot(app.UIAxes_right,app.meanProperty,app.z,'LineWidth',2,'Color','k')
               hold(app.UIAxes_right, 'on')
               plot(app.UIAxes_right,minProperty,app.z,'--','LineWidth',1,'Color','k')
               plot(app.UIAxes_right,maxProperty,app.z,'--','LineWidth',1,'Color','k')
                hold(app.UIAxes_right, 'off')
            elseif strcmp(app.ChoiceDropDown.Value, 'Plot Mean Only')
                 plot(app.UIAxes_right,app.meanProperty,app.z,'LineWidth',2,'Color','k')
            elseif strcmp(app.ChoiceDropDown.Value, 'Plot Mean + 1σ')
                plot(app.UIAxes_right,app.meanProperty,app.z,'LineWidth',2,'Color','k')
                hold(app.UIAxes_right, 'on')
                plot(app.UIAxes_right,app.meanProperty+app.stdProperty,app.z,'--','LineWidth',1,'Color','k')
                plot(app.UIAxes_right,app.meanProperty-app.stdProperty,app.z,'--','LineWidth',1,'Color','k')
                hold(app.UIAxes_right, 'off')
            elseif strcmp(app.ChoiceDropDown.Value, 'Plot Mean + 2σ')
                plot(app.UIAxes_right,app.meanProperty,app.z,'LineWidth',2,'Color','k')
                hold(app.UIAxes_right, 'on')
                plot(app.UIAxes_right,app.meanProperty+2*app.stdProperty,app.z,'--','LineWidth',1,'Color','k')
                plot(app.UIAxes_right,app.meanProperty-2*app.stdProperty,app.z,'--','LineWidth',1,'Color','k')
                hold(app.UIAxes_right, 'off')
            end
        end

        % Button pushed function: ExportDatatoWorkspaceButton
        function ExportDatatoWorkspaceButtonPushed(app, event)
            labels = {'Depth' ...
                'Pressure' ...
                'Geotherm' ...
                'Average Property' ...
                'Std Properties' ...
                'All Property' ...
                'Rock Constraints'...
                'P indices'...
                'T indices'}; 
            vars = {'z','P','T','averageProperty','stdProperty','property','constraints','pIndices','tIndices'};
            values = {app.z,app.pressure,app.geotherm,app.meanProperty,app.stdProperty,app.allProperties,app.constraints,app.pIndices,app.tIndices};
            export2wsdlg(labels,vars,values);
        end

        % Value changed function: SetAxesCheckBox
        function SetAxesCheckBoxValueChanged(app, event)
            value = app.SetAxesCheckBox.Value;
            app.TempLabel.Visible=value;
            app.PropertyLabel.Visible=value;
            app.MinLabel.Visible=value;
            app.MaxLabel.Visible=value;
            app.rightxAxisMin.Visible=value;
            app.rightxAxisMax.Visible=value;
            app.xAxisMax.Visible=value;
            app.xAxisMin.Visible=value;
            if value
                app.UIAxes_right.XLimMode='manual';
                app.UIAxes_left.XLimMode='manual';
                app.UIAxes_left.XLim=[app.xAxisMin.Value app.xAxisMax.Value];
                app.UIAxes_right.XLim=[app.rightxAxisMin.Value app.rightxAxisMax.Value];
            else
                app.UIAxes_right.XLimMode='auto';
                app.UIAxes_left.XLimMode='auto';
            end

        end

        % Value changed function: xAxisMin
        function xAxisMinValueChanged(app, event)
            app.UIAxes_left.XLim=[app.xAxisMin.Value app.xAxisMax.Value];
            app.UIAxes_right.XLim=[app.rightxAxisMin.Value app.rightxAxisMax.Value];

        end

        % Value changed function: xAxisMax
        function xAxisMaxValueChanged(app, event)
            app.UIAxes_left.XLim=[app.xAxisMin.Value app.xAxisMax.Value];
            app.UIAxes_right.XLim=[app.rightxAxisMin.Value app.rightxAxisMax.Value];

        end

        % Value changed function: rightxAxisMax
        function rightxAxisMaxValueChanged(app, event)
            app.UIAxes_left.XLim=[app.xAxisMin.Value app.xAxisMax.Value];
            app.UIAxes_right.XLim=[app.rightxAxisMin.Value app.rightxAxisMax.Value];
        end

        % Value changed function: rightxAxisMin
        function rightxAxisMinValueChanged(app, event)
            app.UIAxes_left.XLim=[app.xAxisMin.Value app.xAxisMax.Value];
            app.UIAxes_right.XLim=[app.rightxAxisMin.Value app.rightxAxisMax.Value];
        end

        % Value changed function: MaxDepthkmEditField
        function MaxDepthkmEditFieldValueChanged(app, event)
            value = app.MaxDepthkmEditField.Value;
            app.UIAxes_right.YLimMode='manual';
            app.UIAxes_left.YLimMode='manual';
            app.UIAxes_left.YLim=[0 value];
            app.UIAxes_right.YLim=[0 value];
        end

        % Button pushed function: LoadGeothermFileButton
        function LoadGeothermFileButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            if isequal(file,0)
                %   disp('User selected Cancel')
            else
                temporary=load(fullfile(path,file));
                app.inputZ=temporary.z;
                app.inputP=temporary.p;
                app.InputGeothermButton=temporary.t;
                %app.DefaultfileisloadedLabel.Text = [file ' is now loaded.'];
                app.hasLoadedGeotherm=true;
            end
            figure(app.UIFigure);

        end

        % Value changed function: SurfaceQEditField
        function SurfaceQEditFieldValueChanged(app, event)
            if app.UseChapmanCheckBox.Value
                app.HeatProductivityCrustEditField.Value=.4*app.SurfaceQEditField.Value/app.PlateThicknessEditField.Value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_left = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_left, 'Z')
            app.UIAxes_left.YDir = 'reverse';
            app.UIAxes_left.XAxisLocation = 'top';
            app.UIAxes_right = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_right, 'Z')
            app.UIAxes_right.YDir = 'reverse';
            app.UIAxes_right.XAxisLocation = 'top';
            app.CompositionalDropDown = uidropdown(app.UIFigure);
            app.CompositionalDropDown.Items = {'Al2O3', 'CaO', 'Cr2O3', 'FeO', 'MgO', 'Mg #', 'Na2O', 'SiO2'};
            app.CompositionalDropDown.Visible = 'off';
            app.CompositionalDropDown.Value = 'Al2O3';
            app.AddaCompositionalConstraintCheckBox = uicheckbox(app.UIFigure);
            app.AddaCompositionalConstraintCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddaCompositionalConstraintCheckBoxValueChanged, true);
            app.AddaCompositionalConstraintCheckBox.Text = 'Add a Compositional Constraint';
            app.MinimumEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumEditField.Limits = [0 100];
            app.MinimumEditField.Visible = 'off';
            app.MaximumEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumEditField.Limits = [0 100];
            app.MaximumEditField.Visible = 'off';
            app.MaximumEditField.Value = 100;
            app.MinimumLabel = uilabel(app.UIFigure);
            app.MinimumLabel.Visible = 'off';
            app.MinimumLabel.Text = 'Minimum';
            app.MaximumLabel = uilabel(app.UIFigure);
            app.MaximumLabel.Visible = 'off';
            app.MaximumLabel.Text = 'Maximum';
            app.ExportDatatoWorkspaceButton = uibutton(app.UIFigure, 'push');
            app.ExportDatatoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDatatoWorkspaceButtonPushed, true);
            app.ExportDatatoWorkspaceButton.Text = 'Export Data to Workspace';
            app.LoadSpeedFileButton = uibutton(app.UIFigure, 'push');
            app.LoadSpeedFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSpeedFileButtonPushed, true);
            app.LoadSpeedFileButton.Text = 'Load Speed File';
            app.DefaultfileisloadedLabel = uilabel(app.UIFigure);
            app.DefaultfileisloadedLabel.Text = 'Default file is loaded.';
            app.GeothermButtonGroup = uibuttongroup(app.UIFigure);
            app.GeothermButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GeothermButtonGroupSelectionChanged, true);
            app.GeothermButtonGroup.Title = 'Geotherm';
            app.HalfSpaceCoolingModelButton = uiradiobutton(app.GeothermButtonGroup);
            app.HalfSpaceCoolingModelButton.Text = 'Half-Space Cooling Model';
            app.HalfSpaceCoolingModelButton.Value = true;
            app.PlateModelButton = uiradiobutton(app.GeothermButtonGroup);
            app.PlateModelButton.Text = 'Plate Model';
            app.ContinentalButton = uiradiobutton(app.GeothermButtonGroup);
            app.ContinentalButton.Text = 'Continental';
            app.InputGeothermButton = uiradiobutton(app.GeothermButtonGroup);
            app.InputGeothermButton.Text = 'Input Geotherm';
            app.RockTypeToPlotDropDownLabel = uilabel(app.UIFigure);
            app.RockTypeToPlotDropDownLabel.Text = 'Rock Type To Plot';
            app.RockTypeToPlotDropDown = uidropdown(app.UIFigure);
            app.RockTypeToPlotDropDown.Items = {'All Peridotites', 'Lherzolites', 'Harzburgites', 'Wehrlites', 'Dunites', 'All Pyroxenites', 'Olivine Webserites', 'Websterites', 'Clinopyroxenites', 'Orthopyroxenites', 'Olivine Orthopyroxenites', 'Olivine Clinopyroxenites'};
            app.RockTypeToPlotDropDown.Value = 'Lherzolites';
            app.UseChapmanCheckBox = uicheckbox(app.UIFigure);
            app.UseChapmanCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseChapmanCheckBoxValueChanged, true);
            app.UseChapmanCheckBox.Visible = 'off';
            app.UseChapmanCheckBox.Text = 'Use Chapman (1986) 40% Rule';
            app.AgeMyrEditFieldLabel = uilabel(app.UIFigure);
            app.AgeMyrEditFieldLabel.Text = 'Age [Myr]';
            app.AgeMyrEditField = uieditfield(app.UIFigure, 'numeric');
            app.AgeMyrEditField.Limits = [0 4600];
            app.AgeMyrEditField.ValueChangedFcn = createCallbackFcn(app, @AgeMyrEditFieldValueChanged, true);
            app.AgeMyrEditField.Value = 60;
            app.kappaEditField = uieditfield(app.UIFigure, 'numeric');
            app.kappaEditField.Limits = [0 1];
            app.kappaEditField.ValueChangedFcn = createCallbackFcn(app, @kappaEditFieldValueChanged, true);
            app.kappaEditField.Value = 1e-06;
            app.kappaEditFieldLabel = uilabel(app.UIFigure);
            app.kappaEditFieldLabel.Text = 'κ [m^2 s^-1]';
            app.DataToPlotDropDownLabel = uilabel(app.UIFigure);
            app.DataToPlotDropDownLabel.Text = 'Data To Plot';
            app.DataToPlotDropDown = uidropdown(app.UIFigure);
            app.DataToPlotDropDown.Items = {'Vp', 'Vs', 'Vp/Vs', 'Density'};
            app.DataToPlotDropDown.Value = 'Vp';
            app.TpotEditField = uieditfield(app.UIFigure, 'numeric');
            app.TpotEditField.Limits = [0 Inf];
            app.TpotEditField.ValueChangedFcn = createCallbackFcn(app, @TpotEditFieldValueChanged, true);
            app.TpotEditField.Value = 1350;
            app.AdiabatCkmLabel = uilabel(app.UIFigure);
            app.AdiabatCkmLabel.Text = 'Adiabat [C/km]';
            app.AdiabatEditField = uieditfield(app.UIFigure, 'numeric');
            app.AdiabatEditField.Limits = [0 Inf];
            app.AdiabatEditField.Value = 0.4;
            app.PlateThicknesskmEditFieldLabel = uilabel(app.UIFigure);
            app.PlateThicknesskmEditFieldLabel.Visible = 'off';
            app.PlateThicknesskmEditFieldLabel.Text = 'PlateThickness [km]';
            app.PlateThicknessEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlateThicknessEditField.Limits = [0.01 Inf];
            app.PlateThicknessEditField.ValueChangedFcn = createCallbackFcn(app, @PlateThicknessEditFieldValueChanged, true);
            app.PlateThicknessEditField.Visible = 'off';
            app.PlateThicknessEditField.Value = 35;
            app.HeatProductivityofMantleWm3Label = uilabel(app.UIFigure);
            app.HeatProductivityofMantleWm3Label.Visible = 'off';
            app.HeatProductivityofMantleWm3Label.Text = 'Heat Productivity of Mantle [µW m^-3]';
            app.HeatProductivityEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeatProductivityEditField.Limits = [0 Inf];
            app.HeatProductivityEditField.Visible = 'off';
            app.HeatProductivityEditField.Value = 0.05;
            app.HeatProductivityCrustLabel = uilabel(app.UIFigure);
            app.HeatProductivityCrustLabel.Visible = 'off';
            app.HeatProductivityCrustLabel.Text = 'Heat Productivity of Crust [µW m^-3]';
            app.HeatProductivityCrustEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeatProductivityCrustEditField.Limits = [0 Inf];
            app.HeatProductivityCrustEditField.Visible = 'off';
            app.HeatProductivityCrustEditField.Value = 0.5;
            app.surfaceQEditFieldLabel = uilabel(app.UIFigure);
            app.surfaceQEditFieldLabel.Text = 'Surface Heat Flux [mW m^-2]';
            app.SurfaceQEditField = uieditfield(app.UIFigure, 'numeric');
            app.SurfaceQEditField.Limits = [0 Inf];
            app.SurfaceQEditField.ValueChangedFcn = createCallbackFcn(app, @SurfaceQEditFieldValueChanged, true);
            app.SurfaceQEditField.Editable = 'off';
            app.SurfaceQEditField.Value = 60;
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot!';
            app.MaxDepthkmEditFieldLabel = uilabel(app.UIFigure);
            app.MaxDepthkmEditFieldLabel.Text = 'Max Depth [km]';
            app.MaxDepthkmEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxDepthkmEditField.Limits = [30 180];
            app.MaxDepthkmEditField.ValueChangedFcn = createCallbackFcn(app, @MaxDepthkmEditFieldValueChanged, true);
            app.MaxDepthkmEditField.Value = 180;
            app.TPotLabel = uilabel(app.UIFigure);
            app.TPotLabel.Text = 'Tpot';
            app.ChoiceDropDown = uidropdown(app.UIFigure);
            app.ChoiceDropDown.Items = {'Plot Mean Only', 'Plot Mean and Range', 'Plot Mean + 1σ', 'Plot Mean + 2σ'};
            app.ChoiceDropDown.Value = 'Plot Mean and Range';
            app.SetAxesCheckBox = uicheckbox(app.UIFigure);
            app.SetAxesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SetAxesCheckBoxValueChanged, true);
            app.SetAxesCheckBox.Text = 'Set Axes';
            app.xAxisMin = uieditfield(app.UIFigure, 'numeric');
            app.xAxisMin.ValueChangedFcn = createCallbackFcn(app, @xAxisMinValueChanged, true);
            app.xAxisMin.Visible = 'off';
            app.xAxisMax = uieditfield(app.UIFigure, 'numeric');
            app.xAxisMax.ValueChangedFcn = createCallbackFcn(app, @xAxisMaxValueChanged, true);
            app.xAxisMax.Visible = 'off';
            app.xAxisMax.Value = 1500;
            app.MinLabel = uilabel(app.UIFigure);
            app.MinLabel.Visible = 'off';
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.UIFigure);
            app.MaxLabel.Visible = 'off';
            app.MaxLabel.Text = 'Max';
            app.TempLabel = uilabel(app.UIFigure);
            app.TempLabel.Visible = 'off';
            app.TempLabel.Text = 'Temp';
            app.rightxAxisMin = uieditfield(app.UIFigure, 'numeric');
            app.rightxAxisMin.ValueChangedFcn = createCallbackFcn(app, @rightxAxisMinValueChanged, true);
            app.rightxAxisMin.Visible = 'off';
            app.rightxAxisMin.Value = 7.5;
            app.rightxAxisMax = uieditfield(app.UIFigure, 'numeric');
            app.rightxAxisMax.ValueChangedFcn = createCallbackFcn(app, @rightxAxisMaxValueChanged, true);
            app.rightxAxisMax.Visible = 'off';
            app.rightxAxisMax.Value = 9;
            app.PropertyLabel = uilabel(app.UIFigure);
            app.PropertyLabel.Visible = 'off';
            app.PropertyLabel.Text = 'Property';
            app.LoadGeothermFileButton = uibutton(app.UIFigure, 'push');
            app.LoadGeothermFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGeothermFileButtonPushed, true);
            app.LoadGeothermFileButton.Text = 'Load Geotherm File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WISTFUL_geotherm

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end