classdef WISTFUL_inversion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ErrorLabel                     matlab.ui.control.Label
        Error2EditField                matlab.ui.control.NumericEditField
        Error1EditField                matlab.ui.control.NumericEditField
        PlotChosenDataHistogramButton  matlab.ui.control.Button
        HistogramTemperatureEditField  matlab.ui.control.NumericEditField
        TemperatureChoiceLabel         matlab.ui.control.Label
        PoissonBox                     matlab.ui.control.CheckBox
        ExportDatatoWorkspaceButton    matlab.ui.control.Button
        TempRangeLabel                 matlab.ui.control.Label
        TempRangeMaxLabel              matlab.ui.control.Label
        TemperatureMaxEditField        matlab.ui.control.NumericEditField
        XSamplesLabel                  matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        BestFitParameterLabel          matlab.ui.control.Label
        ShowBestFitForLabel            matlab.ui.control.Label
        ExtraParameterDropDown         matlab.ui.control.DropDown
        BestFitTemperatureLabel        matlab.ui.control.Label
        BottomDataInputField           matlab.ui.control.NumericEditField
        TopDataInputField              matlab.ui.control.NumericEditField
        TemperatureLabel               matlab.ui.control.Label
        BottomInputLabel               matlab.ui.control.Label
        TopInputLabel                  matlab.ui.control.Label
        rocksPlottedLabel              matlab.ui.control.Label
        MaximumLabel                   matlab.ui.control.Label
        MinimumLabel                   matlab.ui.control.Label
        MaximumEditField               matlab.ui.control.NumericEditField
        MinimumEditField               matlab.ui.control.NumericEditField
        AddaCompositionalConstraintCheckBox  matlab.ui.control.CheckBox
        CompositionalDropDown          matlab.ui.control.DropDown
        RockTypestoFitLabel            matlab.ui.control.Label
        DataToFitDropDown              matlab.ui.control.DropDown
        DataToFitLabel                 matlab.ui.control.Label
        Tree                           matlab.ui.container.CheckBoxTree
        PeridotitesNode                matlab.ui.container.TreeNode
        LherzolitesNode                matlab.ui.container.TreeNode
        HarzburgitesNode               matlab.ui.container.TreeNode
        WehrliteNode                   matlab.ui.container.TreeNode
        DunitesNode                    matlab.ui.container.TreeNode
        PyroxenitesNode                matlab.ui.container.TreeNode
        OlivineWebsteriteNode          matlab.ui.container.TreeNode
        WebsteriteNode                 matlab.ui.container.TreeNode
        ClinopyroxeniteNode            matlab.ui.container.TreeNode
        OrthopyroxeniteNode            matlab.ui.container.TreeNode
        OlivineOrthopyroxeniteNode     matlab.ui.container.TreeNode
        OlivineClinopyroxeniteNode     matlab.ui.container.TreeNode
        DefaultfileisloadedLabel       matlab.ui.control.Label
        FitTypeButtonGroup             matlab.ui.container.ButtonGroup
        AllWithinXButton               matlab.ui.control.RadioButton
        ClosestXButton                 matlab.ui.control.RadioButton
        TemperatureMinEditField        matlab.ui.control.NumericEditField
        PressureGPaEditField           matlab.ui.control.NumericEditField
        PressureGPaEditFieldLabel      matlab.ui.control.Label
        LoadSpeedFileButton            matlab.ui.control.Button
        FitButton                      matlab.ui.control.Button
        UIAxes_right                   matlab.ui.control.UIAxes
        UIAxes_bot                     matlab.ui.control.UIAxes
        UIAxes_top                     matlab.ui.control.UIAxes
    end


    properties (Access = public)
        compositions=load('WISTFUL_compositions_clean.mat'); % specify the path to mat file
        density=load('WISTFUL_densities_clean.mat'); % specify the path to mat file
        modes=load('WISTFUL_parsed_modes_clean.mat'); % specify the path to mat file
        rocktype=load('WISTFUL_rockType.mat'); % specify the path to mat file
        moduli=load('WISTFUL_speeds_moduli_clean.mat'); % specify the path to mat file
        constraints=[];
        tempLoad=[];
        numberFits=[];
        averageLowestError=[];
        errStdAll=[];
        averageProperty=[];
        stdProperty=[];
        histogramData=[];
        hasPlotted=false;
        indices=[];
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TemperatureLabel.Text=['Temperature Range [' char(176) 'C]'];
            app.TemperatureChoiceLabel.Text=['Temperature for Histogram [' char(176) 'C]'];
        end

        % Value changed function: 
        % AddaCompositionalConstraintCheckBox
        function AddaCompositionalConstraintCheckBoxValueChanged(app, event)
            value = app.AddaCompositionalConstraintCheckBox.Value;
            app.CompositionalDropDown.Visible=value;
            app.MaximumEditField.Visible=value;
            app.MinimumEditField.Visible=value;
            app.MinimumLabel.Visible = value;
            app.MaximumLabel.Visible = value;
        end

        % Button pushed function: LoadSpeedFileButton
        function LoadSpeedFileButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            if isequal(file,0)
                %   disp('User selected Cancel')
            else
                app.moduli=load(fullfile(path,file));
                app.DefaultfileisloadedLabel.Text = [file ' is now loaded.'];
            end
            figure(app.UIFigure);
        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
            %first find the P-T conditions we are plotting at:
            Pwant=app.PressureGPaEditField.Value;
            TwantMin=273.1+app.TemperatureMinEditField.Value;
            TwantMax=273.1+app.TemperatureMaxEditField.Value;
            pIndex=find(abs(Pwant-app.moduli.p*1e-9)==min(abs(Pwant-app.moduli.p*1e-9)), 1 );
            tIndexMin=find(abs(TwantMin-app.moduli.t)==min(abs(TwantMin-app.moduli.t)), 1 );
            tIndexMax=find(abs(TwantMax-app.moduli.t)==min(abs(TwantMax-app.moduli.t)), 1 );
            tIndex=tIndexMin:tIndexMax;
            tPlot=round(app.moduli.t(tIndex)-273.1);
            %now let's update the constraints if a compositional constraint
            %is chosen
            if isempty(app.constraints)%constraints starts out empty
                checkedNodes = app.Tree.CheckedNodes;
                app.constraints=false(size(app.rocktype.rocktype));
                for i=1:length(checkedNodes)
                    if ~isempty(checkedNodes(i).NodeData)
                        app.constraints=app.constraints|checkedNodes(i).NodeData==app.rocktype.rocktype;
                    end
                end
            end

            if app.AddaCompositionalConstraintCheckBox.Value%if constraint is checked
                if strcmp(app.CompositionalDropDown.Value,'Al2O3')
                    useData=app.compositions.Al2O3database';
                elseif strcmp(app.CompositionalDropDown.Value,'CaO')
                    useData=app.compositions.CaOdatabase';
                elseif strcmp(app.CompositionalDropDown.Value,'Cr2O3')
                    useData=app.compositions.Cr2O3database';
                elseif strcmp(app.CompositionalDropDown.Value,'FeO')
                    useData=app.compositions.FeOTdatabase';
                elseif strcmp(app.CompositionalDropDown.Value,'MgO')
                    useData=app.compositions.MgOdatabase';
                elseif strcmp(app.CompositionalDropDown.Value,'Mg #')
                    useData=app.compositions.mgnum'*100;
                elseif strcmp(app.CompositionalDropDown.Value,'Na2O')
                    useData=app.compositions.Na2Odatabase';
                elseif strcmp(app.CompositionalDropDown.Value,'SiO2')
                    useData=app.compositions.SiO2database';
                elseif strcmp(app.CompositionalDropDown.Value,'Olivine')
                    useData=squeeze(app.modes.ol(pIndex,tIndex,:));
                elseif strcmp(app.CompositionalDropDown.Value,'Orthopyroxene')
                    useData=squeeze(app.modes.opx(pIndex,tIndex,:));
                elseif strcmp(app.CompositionalDropDown.Value,'Clinopyroxene')
                    useData=squeeze(app.modes.cpx(pIndex,tIndex,:));
                elseif strcmp(app.CompositionalDropDown.Value,'Garnet')
                    useData=squeeze(app.modes.gt(pIndex,tIndex,:));
                elseif strcmp(app.CompositionalDropDown.Value,'Spinel')
                    useData=squeeze(app.modes.sp(pIndex,tIndex,:));
                end
                %now add constraint
                app.constraints=app.constraints&useData<=app.MaximumEditField.Value&useData>=app.MinimumEditField.Value;
            end
            % now let's find data 1, we'll need it regardless of whether
            % there is a 1-d or 2-d plot
            if strcmp(app.ExtraParameterDropDown.Value,'Al2O3')
                data1=app.compositions.Al2O3database';
                app.UIAxes_bot.YLabel.String = 'Al_2O_3 [wt. %]';
                tempsensitive=false;
            elseif strcmp(app.ExtraParameterDropDown.Value,'CaO')
                data1=app.compositions.CaOdatabase';
                app.UIAxes_bot.YLabel.String = 'CaO [wt. %]';
                tempsensitive=false;
            elseif strcmp(app.ExtraParameterDropDown.Value,'Cr2O3')
                data1=app.compositions.Cr2O3database';
                app.UIAxes_bot.YLabel.String = 'Cr_2O_3 [wt. %]';
                tempsensitive=false;
            elseif strcmp(app.ExtraParameterDropDown.Value,'FeO')
                data1=app.compositions.FeOTdatabase';
                app.UIAxes_bot.YLabel.String = 'FeO [wt. %]';
                tempsensitive=false;
            elseif strcmp(app.ExtraParameterDropDown.Value,'MgO')
                data1=app.compositions.MgOdatabase';
                app.UIAxes_bot.YLabel.String = 'MgO [wt. %]';
                tempsensitive=false;
            elseif strcmp(app.ExtraParameterDropDown.Value,'Mg #')
                data1=app.compositions.mgnum'*100;
                app.UIAxes_bot.YLabel.String = 'Mg #';
                tempsensitive=false;
            elseif strcmp(app.ExtraParameterDropDown.Value,'Na2O')
                data1=app.compositions.Na2Odatabase';
                app.UIAxes_bot.YLabel.String = 'Na_2O [wt. %]';
                tempsensitive=false;
            elseif strcmp(app.ExtraParameterDropDown.Value,'SiO2')
                data1=app.compositions.SiO2database';
                app.UIAxes_bot.YLabel.String = 'SiO_2 [wt. %]';
                tempsensitive=false;
            elseif strcmp(app.ExtraParameterDropDown.Value,'Olivine')
                data1=squeeze(app.modes.ol(pIndex,tIndex,:));
                app.UIAxes_bot.YLabel.String = 'Olivine [vol. %]';
                tempsensitive=true;
            elseif strcmp(app.ExtraParameterDropDown.Value,'Orthopyroxene')
                data1=squeeze(app.modes.opx(pIndex,tIndex,:));
                app.UIAxes_bot.YLabel.String = 'Orthopyroxene [vol. %]';
                tempsensitive=true;
            elseif strcmp(app.ExtraParameterDropDown.Value,'Clinopyroxene')
                data1=squeeze(app.modes.cpx(pIndex,tIndex,:));
                app.UIAxes_bot.YLabel.String = 'Clinopyroxene [vol. %]';
                tempsensitive=true;
            elseif strcmp(app.ExtraParameterDropDown.Value,'Garnet')
                data1=squeeze(app.modes.gt(pIndex,tIndex,:));
                app.UIAxes_bot.YLabel.String = 'Garnet [vol. %]';
                tempsensitive=true;
            elseif strcmp(app.ExtraParameterDropDown.Value,'Spinel')
                data1=squeeze(app.modes.sp(pIndex,tIndex,:));
                app.UIAxes_bot.YLabel.String = 'Spinel [vol. %]';
                tempsensitive=true;
            elseif strcmp(app.ExtraParameterDropDown.Value,'Density')
                data1=squeeze(app.density.rockDensity(pIndex,tIndex,:));
                app.UIAxes_bot.YLabel.String = 'Density [kg m^{-3}]';
                tempsensitive=true;
            end
            %set X label's to temperature
            app.UIAxes_bot.XLabel.String = ['T [' char(176) 'C]'];
            app.UIAxes_top.XLabel.String = ['T [' char(176) 'C]'];

            %let's figure out our matching vp and vs values
            value = app.DataToFitDropDown.Value;
            twoValue=true;
            if strcmp(value,'Vp and Vs')
                vpFind=app.TopDataInputField.Value;
                vsFind=app.BottomDataInputField.Value;
            elseif strcmp(value,'Vp and Vp/Vs')
                vpFind=app.TopDataInputField.Value;
                vsFind=app.TopDataInputField.Value/app.BottomDataInputField.Value;
            elseif strcmp(value,'Vs and Vp/Vs')
                vpFind=app.BottomDataInputField.Value*app.TopDataInputField.Value;
                vsFind=app.TopDataInputField.Value;
            elseif strcmp(value,'Vs')
                vsFind=app.TopDataInputField.Value;
                twoValue=false;
                if app.PoissonBox.Value
                    poisson=app.BottomDataInputField.Value;
                    vpFind=vsFind*sqrt((2-2*poisson)/(1-2*poisson));
                    twoValue=true;
                end
            elseif strcmp(value,'Vp')
                vpFind=app.TopDataInputField.Value;
                twoValue=false;
                if app.PoissonBox.Value
                    poisson=app.BottomDataInputField.Value;
                    vsFind=vpFind*sqrt((1-2*poisson)/(2-2*poisson));
                    twoValue=true;
                end
            end
            %lets find the misfit for each constrained sample at every
            %temperature
            if twoValue
                errorAll=100*squeeze(sqrt(((app.moduli.vp(pIndex,tIndex,app.constraints)-vpFind)./vpFind).^2+((app.moduli.vs(pIndex,tIndex,app.constraints)-vsFind)./vsFind).^2));
            elseif strcmp(value,'Vp')
                errorAll=100*squeeze(sqrt(((app.moduli.vp(pIndex,tIndex,app.constraints)-vpFind)./vpFind).^2));
            elseif strcmp(value,'Vs')
                errorAll=100*squeeze(sqrt(((app.moduli.vs(pIndex,tIndex,app.constraints)-vsFind)./vsFind).^2));
            end

            %then we check whether are fitting by closest X or within X
            if app.ClosestXButton.Value
                closestX=round(app.XEditField.Value);
                if sum(app.constraints)<closestX%make sure you don't search for more than what's available
                    closestX=sum(app.constraints);
                end
                app.indices=zeros(closestX,length(tIndex));


                if strcmp(value,'Vp and Vs')
                    [tPlot, app.averageLowestError, bestTemp, errorTemp, foundIndices, errorAllSorted] =...
                        findClosestX(Pwant*1e9, [TwantMin TwantMax]-273.1, app.moduli.t, app.moduli.p, closestX, app.BottomDataInputField.Value, app.moduli.vs,app.constraints,  app.TopDataInputField.Value, app.moduli.vp);
                elseif strcmp(value,'Vp and Vp/Vs')
                    [tPlot, app.averageLowestError, bestTemp, errorTemp, foundIndices, errorAllSorted] =...
                        findClosestX(Pwant*1e9, [TwantMin TwantMax]-273.1, app.moduli.t, app.moduli.p, closestX, app.BottomDataInputField.Value, app.moduli.vp./app.moduli.vs,app.constraints,  app.TopDataInputField.Value, app.moduli.vp);

                elseif strcmp(value,'Vs and Vp/Vs')
                    [tPlot, app.averageLowestError, bestTemp, errorTemp, foundIndices, errorAllSorted] =...
                        findClosestX(Pwant*1e9, [TwantMin TwantMax]-273.1, app.moduli.t, app.moduli.p, closestX, app.BottomDataInputField.Value, app.moduli.vp./app.moduli.vs,app.constraints,  app.TopDataInputField.Value, app.moduli.vs);
                elseif strcmp(value,'Vs')
                    if app.PoissonBox.Value
                        poisson=app.BottomDataInputField.Value;
                        vpFind=vsFind*sqrt((2-2*poisson)/(1-2*poisson));
                        [tPlot, app.averageLowestError, bestTemp, errorTemp, foundIndices, errorAllSorted] =...
                            findClosestX(Pwant*1e9, [TwantMin TwantMax]-273.1, app.moduli.t, app.moduli.p, closestX, vpFind, app.moduli.vp,app.constraints,  app.TopDataInputField.Value, app.moduli.vs);
                    else
                        [tPlot, app.averageLowestError, bestTemp, errorTemp, foundIndices, errorAllSorted] =...
                            findClosestX(Pwant*1e9, [TwantMin TwantMax]-273.1, app.moduli.t, app.moduli.p, closestX, app.TopDataInputField.Value, app.moduli.vs,app.constraints);
                    end
                elseif strcmp(value,'Vp')
                    if app.PoissonBox.Value
                        poisson=app.BottomDataInputField.Value;
                        vsFind=vpFind*sqrt((1-2*poisson)/(2-2*poisson));
                        [tPlot, app.averageLowestError, bestTemp, errorTemp, foundIndices, errorAllSorted] =...
                            findClosestX(Pwant*1e9, [TwantMin TwantMax]-273.1, app.moduli.t, app.moduli.p, closestX, app.TopDataInputField.Value, app.moduli.vp,app.constraints,vsFind,app.moduli.vs);
                    else
                        [tPlot, app.averageLowestError, bestTemp, errorTemp, foundIndices, errorAllSorted] =...
                            findClosestX(Pwant*1e9, [TwantMin TwantMax]-273.1, app.moduli.t, app.moduli.p, closestX, app.TopDataInputField.Value, app.moduli.vp,app.constraints);
                    end
                end
                %now fit the best properties
                [bestProperty, propertyError, app.averageProperty, app.stdProperty] = fitPropertyClosestX(Pwant*1e9, bestTemp,errorTemp,app.moduli.t, app.moduli.p,[TwantMin TwantMax]-273.1,data1',foundIndices, errorAllSorted);
                %and plot
                plot(app.UIAxes_top,tPlot,app.averageLowestError,'k','LineWidth',2);
                app.UIAxes_top.XLim=[app.TemperatureMinEditField.Value app.TemperatureMaxEditField.Value];
                hold(app.UIAxes_bot, 'off')
                legend(app.UIAxes_bot,'off')
                plot(app.UIAxes_bot,tPlot,app.averageProperty,'k','LineWidth',2);
                app.UIAxes_bot.XLim=[app.TemperatureMinEditField.Value app.TemperatureMaxEditField.Value];

                app.BestFitTemperatureLabel.Text=['Best Fit Temperature = ' num2str(round(bestTemp,-1)) char(177) num2str(round(errorTemp,-1)) char(176) 'C'];
                app.BestFitParameterLabel.Text=['Best Fit Property = ' num2str(round(bestProperty,1)) char(177) num2str(round(propertyError,1))];
                app.UIAxes_top.YLabel.String = 'Average RMSE [%]';
                app.HistogramTemperatureEditField.Value=round(bestTemp,-1);
            elseif app.AllWithinXButton.Value
                app.indices=cell(size(tIndex));
                allowedError1=app.Error1EditField.Value;
                if strcmp(value,'Vp and Vs')
                    [tPlot, numWithin, bestFitT, Terror, foundIndices, errorAll] = numWithinError(Pwant*1e9, [TwantMin TwantMax]-273.1, app.moduli.t, app.moduli.p, app.TopDataInputField.Value, allowedError1, app.moduli.vp,...
                        app.constraints,app.BottomDataInputField.Value, app.Error2EditField.Value,app.moduli.vs);
                elseif strcmp(value,'Vp and Vp/Vs')
                    [tPlot, numWithin, bestFitT, Terror, foundIndices, errorAll] = numWithinError(Pwant*1e9, [TwantMin TwantMax]-273.1, app.moduli.t, app.moduli.p, app.TopDataInputField.Value, allowedError1, app.moduli.vp,...
                        app.constraints,app.BottomDataInputField.Value, app.Error2EditField.Value,app.moduli.vp./app.moduli.vs);
                elseif strcmp(value,'Vs and Vp/Vs')
                    [tPlot, numWithin, bestFitT, Terror, foundIndices, errorAll] = numWithinError(Pwant*1e9, [TwantMin TwantMax]-273.1, app.moduli.t, app.moduli.p, app.TopDataInputField.Value, allowedError1, app.moduli.vs,...
                        app.constraints,app.BottomDataInputField.Value, app.Error2EditField.Value,app.moduli.vp./app.moduli.vs);
                elseif strcmp(value,'Vs')
                    if app.PoissonBox.Value
                        poisson=app.BottomDataInputField.Value;
                        poissonError=app.Error2EditField.Value;
                        vpFind=vsFind*sqrt((2-2*poisson)/(1-2*poisson));
                        error2=sqrt(2)/2*poissonError;
                        [tPlot, numWithin, bestFitT, Terror, foundIndices, errorAll] = numWithinError(Pwant*1e9, [TwantMin TwantMax]-273.1, app.moduli.t, app.moduli.p, app.TopDataInputField.Value, allowedError1, app.moduli.vs,...
                            app.constraints,vpFind,error2,app.moduli.vp);
                    else
                        [tPlot, numWithin, bestFitT, Terror, foundIndices, errorAll] = numWithinError(Pwant*1e9, [TwantMin TwantMax]-273.1, app.moduli.t, app.moduli.p, app.TopDataInputField.Value, allowedError1, app.moduli.vs,...
                            app.constraints);
                    end
                elseif strcmp(value,'Vp')
                    if app.PoissonBox.Value
                        poisson=app.BottomDataInputField.Value;
                        poissonError=app.Error2EditField.Value;
                        vsFind=vpFind*sqrt((1-2*poisson)/(2-2*poisson));
                        error2=sqrt(2)/2*poissonError;
                        [tPlot, numWithin, bestFitT, Terror, foundIndices, errorAll] = numWithinError(Pwant*1e9, [TwantMin TwantMax]-273.1, app.moduli.t, app.moduli.p, app.TopDataInputField.Value, allowedError1, app.moduli.vp,...
                            app.constraints,vsFind,error2,app.moduli.vs);
                    else
                        [tPlot, numWithin, bestFitT, Terror, foundIndices, errorAll] = numWithinError(Pwant*1e9, [TwantMin TwantMax]-273.1, app.moduli.t, app.moduli.p, app.TopDataInputField.Value, allowedError1, app.moduli.vs,...
                            app.constraints);
                    end
                end
                propertyAverage=zeros(size(tIndex));
                propertyError=zeros(size(tIndex));
                for i=1:length(tIndex)
                    %                     if i==116
                    %                         a=0;
                    %                     end
                    if tempsensitive
                        [propertyAverage(i), propertyError(i)] = fitPropertyNumWithin(Pwant*1e9, tPlot(i),app.moduli.t,app.moduli.p,tPlot,squeeze(data1(i,:))',foundIndices, errorAll);
                    else
                        [propertyAverage(i), propertyError(i)] = fitPropertyNumWithin(Pwant*1e9, tPlot(i),app.moduli.t,app.moduli.p,tPlot,data1,foundIndices, errorAll);

                    end

                end
                plot(app.UIAxes_top,tPlot,numWithin,'k','LineWidth',2)
                hold(app.UIAxes_bot, 'off')
                app.UIAxes_top.XLim=[app.TemperatureMinEditField.Value app.TemperatureMaxEditField.Value];
                a1=plot(app.UIAxes_bot,tPlot,propertyAverage,'k','LineWidth',2);
                hold(app.UIAxes_bot, 'on')
                a2=plot(app.UIAxes_bot,tPlot,propertyAverage+propertyError,'k--','LineWidth',1);
                plot(app.UIAxes_bot,tPlot,propertyAverage-propertyError,'k--','LineWidth',1)
                app.UIAxes_bot.XLim=[app.TemperatureMinEditField.Value app.TemperatureMaxEditField.Value];
                legend(app.UIAxes_bot,'on')
                legend(app.UIAxes_bot,[a1 a2],{'Average Value',[char(177) '1σ']});
                %[~, I]=max(numWithinError);
                I=find(abs(bestFitT-tPlot)==min(abs(bestFitT-tPlot)), 1 );
                app.numberFits=numWithin;
                app.indices=foundIndices;
                app.averageProperty=propertyAverage;
                app.stdProperty=propertyError;
                app.BestFitTemperatureLabel.Text=['Best Fit Temperature = ' num2str(round(bestFitT,-1)) char(177) num2str(round(Terror,-1)) char(176)  'C'];
                app.BestFitParameterLabel.Text=['Best Fit Property = ' num2str(round(propertyAverage(I),1)) char(177) num2str(round(propertyError(I),1))];
                app.UIAxes_top.YLabel.String = 'Number of Samples';
                app.HistogramTemperatureEditField.Value=round(bestFitT,-1);
            end
            app.tempLoad=tPlot;
            app.hasPlotted=true;
            app.rocksPlottedLabel.Text=['You are plotting ' num2str(sum(app.constraints)) ' rocks.'];
        end

        % Selection changed function: FitTypeButtonGroup
        function FitTypeButtonGroupSelectionChanged(app, event)
            if app.AllWithinXButton.Value
                %app.XEditField.Limits=[0 5];
                app.XEditField.Visible=false;
                %app.XSamplesLabel.Text='X [% RMSE]';
                app.ErrorLabel.Visible=true;
                app.Error1EditField.Visible=true;
                if sum(strcmp(app.DataToFitDropDown.Value,{'Vp and Vs','Vp and Vp/Vs','Vs and Vp/Vs'}))>0||app.PoissonBox.Value
                    app.Error2EditField.Visible=true;
                end
                app.XSamplesLabel.Visible=false;
            elseif app.ClosestXButton.Value
                app.XEditField.Visible=true;
                app.ErrorLabel.Visible=false;
                app.Error1EditField.Visible=false;
                app.Error2EditField.Visible=false;
                app.XSamplesLabel.Visible=true;
            end
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            checkedNodes = app.Tree.CheckedNodes;
            app.constraints=false(size(app.rocktype.rocktype));
            for i=1:length(checkedNodes)
                if ~isempty(checkedNodes(i).NodeData)
                    app.constraints=app.constraints|checkedNodes(i).NodeData==app.rocktype.rocktype;
                end
            end
        end

        % Callback function
        function SetAxesCheckBoxValueChanged(app, event)

        end

        % Callback function
        function xAxisMinValueChanged(app, event)
            %value = app.xAxisMin.Value;
            app.UIAxes_top.XLim=[app.xAxisMin.Value app.xAxisMax.Value];
            app.UIAxes_top.YLim=[app.yAxisMin.Value app.yAxisMax.Value];
        end

        % Callback function
        function xAxisMaxValueChanged(app, event)
            app.UIAxes_top.XLim=[app.xAxisMin.Value app.xAxisMax.Value];
            app.UIAxes_top.YLim=[app.yAxisMin.Value app.yAxisMax.Value];
        end

        % Callback function
        function yAxisMaxValueChanged(app, event)
            app.UIAxes_top.XLim=[app.xAxisMin.Value app.xAxisMax.Value];
            app.UIAxes_top.YLim=[app.yAxisMin.Value app.yAxisMax.Value];
        end

        % Callback function
        function yAxisMinValueChanged(app, event)
            app.UIAxes_top.XLim=[app.xAxisMin.Value app.xAxisMax.Value];
            app.UIAxes_top.YLim=[app.yAxisMin.Value app.yAxisMax.Value];
        end

        % Value changed function: DataToFitDropDown
        function DataToFitDropDownValueChanged(app, event)
            value = app.DataToFitDropDown.Value;
            if strcmp(value,'Vp and Vs')
                app.TopInputLabel.Text = 'Vp [km/s]';
                app.BottomInputLabel.Text = 'Vs [km/s]';
                app.BottomInputLabel.Visible = true;
                app.BottomDataInputField.Visible = true;
                app.PoissonBox.Visible=false;
                if app.AllWithinXButton.Value
                    app.Error2EditField.Visible=true;
                end
            elseif strcmp(value,'Vp and Vp/Vs')
                app.TopInputLabel.Text = 'Vp [km/s]';
                app.BottomInputLabel.Text = 'Vp/Vs';
                app.BottomInputLabel.Visible = true;
                app.BottomDataInputField.Visible = true;
                app.PoissonBox.Visible=false;
                if app.AllWithinXButton.Value
                    app.Error2EditField.Visible=true;
                end

            elseif strcmp(value,'Vs and Vp/Vs')
                app.TopInputLabel.Text = 'Vs [km/s]';
                app.BottomInputLabel.Text = 'Vp/Vs';
                app.BottomInputLabel.Visible = true;
                app.BottomDataInputField.Visible = true;
                app.PoissonBox.Visible=false;
                if app.AllWithinXButton.Value
                    app.Error2EditField.Visible=true;
                end

            elseif strcmp(value,'Vp')
                app.TopInputLabel.Text = 'Vp [km/s]';
                app.BottomInputLabel.Text = 'Poisson Ratio';
                app.BottomInputLabel.Visible = app.PoissonBox.Value;
                app.BottomDataInputField.Visible = app.PoissonBox.Value;
                app.PoissonBox.Visible=true;
                if app.AllWithinXButton.Value
                    app.Error2EditField.Visible=app.PoissonBox.Value;
                end

            elseif strcmp(value,'Vs')
                app.TopInputLabel.Text = 'Vs [km/s]';
                app.BottomInputLabel.Text = 'Poisson Ratio';
                app.BottomInputLabel.Visible = app.PoissonBox.Value;
                app.BottomDataInputField.Visible = app.PoissonBox.Value;
                app.PoissonBox.Visible=true;
                if app.AllWithinXButton.Value
                    app.Error2EditField.Visible=app.PoissonBox.Value;
                end

            end
        end

        % Button pushed function: ExportDatatoWorkspaceButton
        function ExportDatatoWorkspaceButtonPushed(app, event)
            if app.ClosestXButton.Value
                labels = {'Temperature' ...
                    'Average RMSE' ...
                    'Average Property' ...
                    'Std Property' ...
                    'Rock Constraints' ...
                    'Post Constraint Indices'...
                    'Histogram Data'};
                vars = {'temperature','rmse','averageProperty','stdProperty','constraints','indices','histogramData'};
                values = {app.tempLoad,app.averageLowestError,app.averageProperty,app.stdProperty,app.constraints,app.indices,app.histogramData};
            else
                labels = {'Temperature' ...
                    'Num Within Error' ...
                    'Average Property' ...
                    'Std Property' ...
                    'Rock Constraints' ...
                    'Post Constraint Indices'...
                    'Histogram Data'};
                vars = {'temperature','numWithin','averageProperty','stdProperty','constraints','indices','histogramData'};
                values = {app.tempLoad,app.numberFits,app.averageProperty, app.stdProperty,app.constraints,app.indices,app.histogramData};
            end
            export2wsdlg(labels,vars,values);
        end

        % Value changed function: PoissonBox
        function PoissonBoxValueChanged(app, event)
            value = app.PoissonBox.Value;
            app.BottomDataInputField.Visible = value;
            app.BottomInputLabel.Visible = value;
            if app.AllWithinXButton.Value
                app.Error2EditField.Visible=value;
            end

        end

        % Button pushed function: PlotChosenDataHistogramButton
        function PlotChosenDataHistogramButtonPushed(app, event)
            if app.hasPlotted
                tFind=round(app.HistogramTemperatureEditField.Value)+273.1;
                tIndex=find(abs(tFind-app.moduli.t)==min(abs(tFind-app.moduli.t)), 1 );
                tIndex2=find(abs(tFind-app.tempLoad-273.1)==min(abs(tFind-app.tempLoad-273.1)), 1 );
                Pwant=app.PressureGPaEditField.Value;
                pIndex=find(abs(Pwant-app.moduli.p*1e-9)==min(abs(Pwant-app.moduli.p*1e-9)), 1 );
                if strcmp(app.ExtraParameterDropDown.Value,'Al2O3')
                    data2=app.compositions.Al2O3database';
                    app.UIAxes_right.XLabel.String = 'Al_2O_3 [wt. %]';
                elseif strcmp(app.ExtraParameterDropDown.Value,'CaO')
                    data2=app.compositions.CaOdatabase';
                    app.UIAxes_right.XLabel.String = 'CaO [wt. %]';
                elseif strcmp(app.ExtraParameterDropDown.Value,'Cr2O3')
                    data2=app.compositions.Cr2O3database';
                    app.UIAxes_right.XLabel.String = 'Cr_2O_3 [wt. %]';
                elseif strcmp(app.ExtraParameterDropDown.Value,'FeO')
                    data2=app.compositions.FeOTdatabase';
                    app.UIAxes_right.XLabel.String = 'FeO [wt. %]';
                elseif strcmp(app.ExtraParameterDropDown.Value,'MgO')
                    data2=app.compositions.MgOdatabase';
                    app.UIAxes_right.XLabel.String = 'MgO [wt. %]';
                elseif strcmp(app.ExtraParameterDropDown.Value,'Mg #')
                    data2=app.compositions.mgnum'*100;
                    app.UIAxes_right.XLabel.String = 'Mg #';
                elseif strcmp(app.ExtraParameterDropDown.Value,'Na2O')
                    data2=app.compositions.Na2Odatabase';
                    app.UIAxes_right.XLabel.String = 'Na_2O [wt. %]';
                elseif strcmp(app.ExtraParameterDropDown.Value,'SiO2')
                    data2=app.compositions.SiO2database';
                    app.UIAxes_right.XLabel.String = 'SiO_2 [wt. %]';
                elseif strcmp(app.ExtraParameterDropDown.Value,'Olivine')
                    data2=squeeze(app.modes.ol(pIndex,tIndex,:));
                    app.UIAxes_right.XLabel.String = 'Olivine [vol. %]';
                elseif strcmp(app.ExtraParameterDropDown.Value,'Orthopyroxene')
                    data2=squeeze(app.modes.opx(pIndex,tIndex,:));
                    app.UIAxes_right.XLabel.String = 'Orthopyroxene [vol. %]';
                elseif strcmp(app.ExtraParameterDropDown.Value,'Clinopyroxene')
                    data2=squeeze(app.modes.cpx(pIndex,tIndex,:));
                    app.UIAxes_right.XLabel.String = 'Clinopyroxene [vol. %]';
                elseif strcmp(app.ExtraParameterDropDown.Value,'Garnet')
                    data2=squeeze(app.modes.gt(pIndex,tIndex,:));
                    app.UIAxes_right.XLabel.String = 'Garnet [vol. %]';
                elseif strcmp(app.ExtraParameterDropDown.Value,'Spinel')
                    data2=squeeze(app.modes.sp(pIndex,tIndex,:));
                    app.UIAxes_right.XLabel.String = 'Spinel [vol. %]';
                elseif strcmp(app.ExtraParameterDropDown.Value,'Density')
                    data2=squeeze(app.density.rockDensity(pIndex,tIndex,:));
                    app.UIAxes_right.XLabel.String = 'Density [kg m^{-3}]';
                end
                app.UIAxes_right.YLabel.String = '# Samples';
                if app.AddaCompositionalConstraintCheckBox.Value%if constraint is checked
                    if strcmp(app.CompositionalDropDown.Value,'Al2O3')
                        useData=app.compositions.Al2O3database';
                    elseif strcmp(app.CompositionalDropDown.Value,'CaO')
                        useData=app.compositions.CaOdatabase';
                    elseif strcmp(app.CompositionalDropDown.Value,'Cr2O3')
                        useData=app.compositions.Cr2O3database';
                    elseif strcmp(app.CompositionalDropDown.Value,'FeO')
                        useData=app.compositions.FeOTdatabase';
                    elseif strcmp(app.CompositionalDropDown.Value,'MgO')
                        useData=app.compositions.MgOdatabase';
                    elseif strcmp(app.CompositionalDropDown.Value,'Mg #')
                        useData=app.compositions.mgnum'*100;
                    elseif strcmp(app.CompositionalDropDown.Value,'Na2O')
                        useData=app.compositions.Na2Odatabase';
                    elseif strcmp(app.CompositionalDropDown.Value,'SiO2')
                        useData=app.compositions.SiO2database';
                    elseif strcmp(app.CompositionalDropDown.Value,'Olivine')
                        useData=squeeze(app.modes.ol(pIndex,tIndex,:));
                    elseif strcmp(app.CompositionalDropDown.Value,'Orthopyroxene')
                        useData=squeeze(app.modes.opx(pIndex,tIndex,:));
                    elseif strcmp(app.CompositionalDropDown.Value,'Clinopyroxene')
                        useData=squeeze(app.modes.cpx(pIndex,tIndex,:));
                    elseif strcmp(app.CompositionalDropDown.Value,'Garnet')
                        useData=squeeze(app.modes.gt(pIndex,tIndex,:));
                    elseif strcmp(app.CompositionalDropDown.Value,'Spinel')
                        useData=squeeze(app.modes.sp(pIndex,tIndex,:));
                    end
                    %now add constraint
                    app.constraints=app.constraints&useData<=app.MaximumEditField.Value&useData>=app.MinimumEditField.Value;
                end
                value = app.DataToFitDropDown.Value;
                twoValue=true;
                if strcmp(value,'Vp and Vs')
                    vpFind=app.TopDataInputField.Value;
                    vsFind=app.BottomDataInputField.Value;
                elseif strcmp(value,'Vp and Vp/Vs')
                    vpFind=app.TopDataInputField.Value;
                    vsFind=app.TopDataInputField.Value/app.BottomDataInputField.Value;
                elseif strcmp(value,'Vs and Vp/Vs')
                    vpFind=app.BottomDataInputField.Value*app.TopDataInputField.Value;
                    vsFind=app.TopDataInputField.Value;
                elseif strcmp(value,'Vs')
                    vsFind=app.TopDataInputField.Value;
                    twoValue=false;
                    if app.PoissonBox.Value
                        poisson=app.BottomDataInputField.Value;
                        vpFind=vsFind*sqrt((2-2*poisson)/(1-2*poisson));
                        twoValue=true;
                    end
                elseif strcmp(value,'Vp')
                    vpFind=app.TopDataInputField.Value;
                    twoValue=false;
                    if app.PoissonBox.Value
                        poisson=app.BottomDataInputField.Value;
                        vsFind=vpFind*sqrt((1-2*poisson)/(2-2*poisson));
                        twoValue=true;
                    end
                end
                %lets find the misfit for each constrained sample at every
                %temperature
                if twoValue
                    errorAll=squeeze(sqrt(((app.moduli.vp(pIndex,tIndex,app.constraints)-vpFind)./vpFind).^2+((app.moduli.vs(pIndex,tIndex,app.constraints)-vsFind)./vsFind).^2));
                elseif strcmp(value,'Vp')
                    errorAll=squeeze(sqrt(((app.moduli.vp(pIndex,tIndex,app.constraints)-vpFind)./vpFind).^2));
                elseif strcmp(value,'Vs')
                    errorAll=squeeze(sqrt(((app.moduli.vs(pIndex,tIndex,app.constraints)-vsFind)./vsFind).^2));
                end
                if app.ClosestXButton.Value%closest X
                    closestX=round(app.XEditField.Value);
                    if sum(app.constraints)<closestX
                        closestX=sum(app.constraints);
                    end
                    [~,isort]=sort(errorAll);
                    app.histogramData=data2(isort(1:closestX));
                    histogram(app.UIAxes_right,app.histogramData,'FaceColor',[179, 239, 55]/256,'LineWidth',1);

                elseif app.AllWithinXButton.Value  %all within value
                    %allowedError=app.XEditField.Value;
                    useIndex=app.indices{tIndex2};
                    app.histogramData=data2(useIndex);
                    histogram(app.UIAxes_right,app.histogramData,'FaceColor',[179, 239, 55]/256,'LineWidth',1);
                end
            end
        end

        % Value changed function: TemperatureMaxEditField
        function TemperatureMaxEditFieldValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_top = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_top, 'Z')
            app.UIAxes_bot = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_bot, 'Z')
            app.UIAxes_right = uiaxes(app.UIFigure);
            app.FitButton = uibutton(app.UIFigure, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Text = 'Fit!';
            app.LoadSpeedFileButton = uibutton(app.UIFigure, 'push');
            app.LoadSpeedFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSpeedFileButtonPushed, true);
            app.LoadSpeedFileButton.Text = 'Load Speed File';
            app.PressureGPaEditFieldLabel = uilabel(app.UIFigure);
            app.PressureGPaEditFieldLabel.Text = 'Pressure [GPa]';
            app.PressureGPaEditField = uieditfield(app.UIFigure, 'numeric');
            app.PressureGPaEditField.Limits = [0.5 6];
            app.PressureGPaEditField.Value = 2;
            app.TemperatureMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.TemperatureMinEditField.Limits = [300 1600];
            app.TemperatureMinEditField.Value = 300;
            app.FitTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.FitTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FitTypeButtonGroupSelectionChanged, true);
            app.FitTypeButtonGroup.Title = 'Fit Type';
            app.ClosestXButton = uiradiobutton(app.FitTypeButtonGroup);
            app.ClosestXButton.Text = 'Closest X';
            app.ClosestXButton.Value = true;
            app.AllWithinXButton = uiradiobutton(app.FitTypeButtonGroup);
            app.AllWithinXButton.Text = 'All Within X';
            app.DefaultfileisloadedLabel = uilabel(app.UIFigure);
            app.DefaultfileisloadedLabel.Text = 'Default file is loaded.';
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.PeridotitesNode = uitreenode(app.Tree);
            app.PeridotitesNode.Text = 'Peridotites';
            app.LherzolitesNode = uitreenode(app.PeridotitesNode);
            app.LherzolitesNode.NodeData = 4;
            app.LherzolitesNode.Text = 'Lherzolites';
            app.HarzburgitesNode = uitreenode(app.PeridotitesNode);
            app.HarzburgitesNode.NodeData = 2;
            app.HarzburgitesNode.Text = 'Harzburgites';
            app.WehrliteNode = uitreenode(app.PeridotitesNode);
            app.WehrliteNode.NodeData = 3;
            app.WehrliteNode.Text = 'Wehrlite';
            app.DunitesNode = uitreenode(app.PeridotitesNode);
            app.DunitesNode.NodeData = 1;
            app.DunitesNode.Text = 'Dunites';
            app.PyroxenitesNode = uitreenode(app.Tree);
            app.PyroxenitesNode.Text = 'Pyroxenites';
            app.OlivineWebsteriteNode = uitreenode(app.PyroxenitesNode);
            app.OlivineWebsteriteNode.NodeData = 5;
            app.OlivineWebsteriteNode.Text = 'Olivine Websterite';
            app.WebsteriteNode = uitreenode(app.PyroxenitesNode);
            app.WebsteriteNode.NodeData = 6;
            app.WebsteriteNode.Text = 'Websterite';
            app.ClinopyroxeniteNode = uitreenode(app.PyroxenitesNode);
            app.ClinopyroxeniteNode.NodeData = 8;
            app.ClinopyroxeniteNode.Text = 'Clinopyroxenite';
            app.OrthopyroxeniteNode = uitreenode(app.PyroxenitesNode);
            app.OrthopyroxeniteNode.NodeData = 7;
            app.OrthopyroxeniteNode.Text = 'Orthopyroxenite';
            app.OlivineOrthopyroxeniteNode = uitreenode(app.PyroxenitesNode);
            app.OlivineOrthopyroxeniteNode.NodeData = 10;
            app.OlivineOrthopyroxeniteNode.Text = 'Olivine Orthopyroxenite';
            app.OlivineClinopyroxeniteNode = uitreenode(app.PyroxenitesNode);
            app.OlivineClinopyroxeniteNode.NodeData = 11;
            app.OlivineClinopyroxeniteNode.Text = 'Olivine Clinopyroxenite';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.LherzolitesNode, app.HarzburgitesNode, app.WehrliteNode, app.DunitesNode, app.OlivineWebsteriteNode, app.WebsteriteNode, app.ClinopyroxeniteNode, app.OrthopyroxeniteNode, app.OlivineOrthopyroxeniteNode, app.OlivineClinopyroxeniteNode, app.PeridotitesNode, app.PyroxenitesNode];
            app.DataToFitLabel = uilabel(app.UIFigure);
            app.DataToFitLabel.Text = 'Data To Fit:';
            app.DataToFitDropDown = uidropdown(app.UIFigure);
            app.DataToFitDropDown.Items = {'Vp and Vs', 'Vp and Vp/Vs', 'Vs and Vp/Vs', 'Vp', 'Vs'};
            app.DataToFitDropDown.ValueChangedFcn = createCallbackFcn(app, @DataToFitDropDownValueChanged, true);
            app.DataToFitDropDown.Value = 'Vp and Vs';
            app.RockTypestoFitLabel = uilabel(app.UIFigure);
            app.RockTypestoFitLabel.Text = 'Rock Types to Fit';
            app.CompositionalDropDown = uidropdown(app.UIFigure);
            app.CompositionalDropDown.Items = {'Al2O3', 'CaO', 'Cr2O3', 'FeO', 'MgO', 'Mg #', 'Na2O', 'SiO2'};
            app.CompositionalDropDown.Visible = 'off';
            app.CompositionalDropDown.Value = 'Al2O3';
            app.AddaCompositionalConstraintCheckBox = uicheckbox(app.UIFigure);
            app.AddaCompositionalConstraintCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddaCompositionalConstraintCheckBoxValueChanged, true);
            app.AddaCompositionalConstraintCheckBox.Text = 'Add a Compositional Constraint';
            app.MinimumEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumEditField.Limits = [0 100];
            app.MinimumEditField.Visible = 'off';
            app.MaximumEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumEditField.Limits = [0 100];
            app.MaximumEditField.Visible = 'off';
            app.MaximumEditField.Value = 100;
            app.MinimumLabel = uilabel(app.UIFigure);
            app.MinimumLabel.Visible = 'off';
            app.MinimumLabel.Text = 'Minimum';
            app.MaximumLabel = uilabel(app.UIFigure);
            app.MaximumLabel.Visible = 'off';
            app.MaximumLabel.Text = 'Maximum';
            app.rocksPlottedLabel = uilabel(app.UIFigure);
            app.rocksPlottedLabel.Text = 'This label shows how many rocks are fit to.';
            app.TopInputLabel = uilabel(app.UIFigure);
            app.TopInputLabel.Text = 'Vp [km/s]';
            app.BottomInputLabel = uilabel(app.UIFigure);
            app.BottomInputLabel.Text = 'Vs [km/s]';
            app.TemperatureLabel = uilabel(app.UIFigure);
            app.TemperatureLabel.Text = 'Temperature';
            app.TopDataInputField = uieditfield(app.UIFigure, 'numeric');
            app.TopDataInputField.Limits = [0 10];
            app.TopDataInputField.Value = 8;
            app.BottomDataInputField = uieditfield(app.UIFigure, 'numeric');
            app.BottomDataInputField.Limits = [0 10];
            app.BottomDataInputField.Value = 4.6;
            app.BestFitTemperatureLabel = uilabel(app.UIFigure);
            app.BestFitTemperatureLabel.Text = 'Best Fit Temperature = ';
            app.ExtraParameterDropDown = uidropdown(app.UIFigure);
            app.ExtraParameterDropDown.Items = {'Al2O3', 'CaO', 'Cr2O3', 'FeO', 'MgO', 'Mg #', 'Na2O', 'SiO2', 'Olivine', 'Orthopyroxene', 'Clinopyroxene', 'Garnet', 'Spinel', 'Density'};
            app.ExtraParameterDropDown.Value = 'Al2O3';
            app.ShowBestFitForLabel = uilabel(app.UIFigure);
            app.ShowBestFitForLabel.Text = 'Show Best Fit For:';
            app.BestFitParameterLabel = uilabel(app.UIFigure);
            app.BestFitParameterLabel.Text = 'Best Fit Parameter = ';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.Limits = [1 500];
            app.XEditField.Value = 100;
            app.XSamplesLabel = uilabel(app.UIFigure);
            app.XSamplesLabel.Text = 'X [# Samples]';
            app.TemperatureMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.TemperatureMaxEditField.Limits = [300 1600];
            app.TemperatureMaxEditField.ValueChangedFcn = createCallbackFcn(app, @TemperatureMaxEditFieldValueChanged, true);
            app.TemperatureMaxEditField.Value = 1400;
            app.TempRangeMaxLabel = uilabel(app.UIFigure);
            app.TempRangeMaxLabel.Text = 'Minimum';
            app.TempRangeLabel = uilabel(app.UIFigure);
            app.TempRangeLabel.Text = 'Maximum';
            app.ExportDatatoWorkspaceButton = uibutton(app.UIFigure, 'push');
            app.ExportDatatoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDatatoWorkspaceButtonPushed, true);
            app.ExportDatatoWorkspaceButton.Text = 'Export Data to Workspace';
            app.PoissonBox = uicheckbox(app.UIFigure);
            app.PoissonBox.ValueChangedFcn = createCallbackFcn(app, @PoissonBoxValueChanged, true);
            app.PoissonBox.Visible = 'off';
            app.PoissonBox.Text = 'Assume Poisson Ratio';
            app.TemperatureChoiceLabel = uilabel(app.UIFigure);
            app.HistogramTemperatureEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotChosenDataHistogramButton = uibutton(app.UIFigure, 'push');
            app.PlotChosenDataHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotChosenDataHistogramButtonPushed, true);
            app.PlotChosenDataHistogramButton.Text = 'Plot Chosen Data Histogram';
            app.Error1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Error1EditField.Limits = [0 5];
            app.Error1EditField.Visible = 'off';
            app.Error1EditField.Value = 0.5;
            app.Error2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Error2EditField.Limits = [0 5];
            app.Error2EditField.Visible = 'off';
            app.Error2EditField.Value = 0.5;
            app.ErrorLabel = uilabel(app.UIFigure);
            app.ErrorLabel.Visible = 'off';
            app.ErrorLabel.Text = 'Error [%]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WISTFUL_inversion

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end