classdef WISTFUL_relations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ExportDatatoWorkspaceButton   matlab.ui.control.Button
        YLabel                        matlab.ui.control.Label
        XLabel                        matlab.ui.control.Label
        MaxLabel                      matlab.ui.control.Label
        MinLabel                      matlab.ui.control.Label
        yAxisMin                      matlab.ui.control.NumericEditField
        yAxisMax                      matlab.ui.control.NumericEditField
        xAxisMax                      matlab.ui.control.NumericEditField
        xAxisMin                      matlab.ui.control.NumericEditField
        SetAxesCheckBox               matlab.ui.control.CheckBox
        TemperatureLabel              matlab.ui.control.Label
        Data2Label                    matlab.ui.control.Label
        rocksPlottedLabel             matlab.ui.control.Label
        MaximumLabel                  matlab.ui.control.Label
        MinimumLabel                  matlab.ui.control.Label
        MaximumEditField              matlab.ui.control.NumericEditField
        MinimumEditField              matlab.ui.control.NumericEditField
        AddaCompositionalConstraintCheckBox  matlab.ui.control.CheckBox
        CompositionalDropDown         matlab.ui.control.DropDown
        RockTypestoPlotLabel          matlab.ui.control.Label
        Data2YAxisDropDown            matlab.ui.control.DropDown
        Data1XAxisDropDown            matlab.ui.control.DropDown
        Data1XAxisDropDownLabel       matlab.ui.control.Label
        Tree                          matlab.ui.container.CheckBoxTree
        PeridotitesNode               matlab.ui.container.TreeNode
        LherzolitesNode               matlab.ui.container.TreeNode
        HarzburgitesNode              matlab.ui.container.TreeNode
        WehrliteNode                  matlab.ui.container.TreeNode
        DunitesNode                   matlab.ui.container.TreeNode
        PyroxenitesNode               matlab.ui.container.TreeNode
        OlivineWebsteriteNode         matlab.ui.container.TreeNode
        WebsteriteNode                matlab.ui.container.TreeNode
        ClinopyroxeniteNode           matlab.ui.container.TreeNode
        OrthopyroxeniteNode           matlab.ui.container.TreeNode
        OlivineOrthopyroxeniteNode    matlab.ui.container.TreeNode
        OlivineClinopyroxeniteNode    matlab.ui.container.TreeNode
        DefaultfileisloadedLabel      matlab.ui.control.Label
        PlotTypeButtonGroup           matlab.ui.container.ButtonGroup
        DScatterButton_2              matlab.ui.control.RadioButton
        HistogramButton_2             matlab.ui.control.RadioButton
        PressureGPaEditField          matlab.ui.control.NumericEditField
        PressureGPaEditFieldLabel     matlab.ui.control.Label
        Temperaturechar176CEditField  matlab.ui.control.NumericEditField
        LoadSpeedFileButton           matlab.ui.control.Button
        PlotButton                    matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end


    properties (Access = public)
        compositions=load('WISTFUL_compositions_clean.mat'); % specify the path to mat file
        density=load('WISTFUL_densities_clean.mat'); % specify the path to mat file
        modes=load('WISTFUL_parsed_modes_clean.mat'); % specify the path to mat file
        rocktype=load('WISTFUL_rockType.mat'); % specify the path to mat file
        moduli=load('WISTFUL_speeds_moduli_clean.mat'); % specify the path to mat file
        constraints=[];
        data1=[];
        data2=[];
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TemperatureLabel.Text=['Temperature [' char(176) 'C]'];
        end

        % Value changed function: 
        % AddaCompositionalConstraintCheckBox
        function AddaCompositionalConstraintCheckBoxValueChanged(app, event)
            value = app.AddaCompositionalConstraintCheckBox.Value;
            app.CompositionalDropDown.Visible=value;
            app.MaximumEditField.Visible=value;
            app.MinimumEditField.Visible=value;
            app.MinimumLabel.Visible = value;
            app.MaximumLabel.Visible = value;
        end

        % Button pushed function: LoadSpeedFileButton
        function LoadSpeedFileButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            if isequal(file,0)
            %    disp('User selected Cancel')
            else
                app.moduli=load(fullfile(path,file));
                app.DefaultfileisloadedLabel.Text = [file ' is now loaded.'];
            end
            figure(app.UIFigure);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            %app.PlotButton.Text=num2str(app.moduli.vs(1),3);
            %             plot(app.UIAxes,[1 2 3 4],'-r');
            %             hold(app.UIAxes);
            %             plot(app.UIAxes,[10 9 4 7],'--b');
            %first find the P-T conditions we are plotting at:
            Pwant=app.PressureGPaEditField.Value;
            Twant=273.1+app.Temperaturechar176CEditField.Value;
            pIndex=find(abs(Pwant-app.moduli.p*1e-4)==min(abs(Pwant-app.moduli.p*1e-4)), 1 );
            tIndex=find(abs(Twant-app.moduli.t)==min(abs(Twant-app.moduli.t)), 1 );
            %now let's update the constraints if a compositional constraint
            %is chosen
            if isempty(app.constraints)%constraints starts out empty
                checkedNodes = app.Tree.CheckedNodes;
                app.constraints=false(size(app.rocktype.rocktype));
                for i=1:length(checkedNodes)
                    if ~isempty(checkedNodes(i).NodeData)
                        app.constraints=app.constraints|checkedNodes(i).NodeData==app.rocktype.rocktype;
                    end
                end
            end
            if app.AddaCompositionalConstraintCheckBox.Value%if constraint is checked
                if strcmp(app.CompositionalDropDown.Value,'Al2O3')
                    useData=app.compositions.Al2O3database';
                elseif strcmp(app.CompositionalDropDown.Value,'CaO')
                    useData=app.compositions.CaOdatabase';
                elseif strcmp(app.CompositionalDropDown.Value,'Cr2O3')
                    useData=app.compositions.Cr2O3database';
                elseif strcmp(app.CompositionalDropDown.Value,'FeO')
                    useData=app.compositions.FeOTdatabase';
                elseif strcmp(app.CompositionalDropDown.Value,'Mg #')
                    useData=app.compositions.mgnum*100';
                elseif strcmp(app.CompositionalDropDown.Value,'MgO')
                    useData=app.compositions.MgOdatabase';
                elseif strcmp(app.CompositionalDropDown.Value,'Na2O')
                    useData=app.compositions.Na2Odatabase';
                elseif strcmp(app.CompositionalDropDown.Value,'SiO2')
                    useData=app.compositions.SiO2database';
                elseif strcmp(app.CompositionalDropDown.Value,'Olivine')
                    useData=squeeze(app.modes.ol(pIndex,tIndex,:));
                elseif strcmp(app.CompositionalDropDown.Value,'Orthopyroxene')
                    useData=squeeze(app.modes.opx(pIndex,tIndex,:));
                elseif strcmp(app.CompositionalDropDown.Value,'Clinopyroxene')
                    useData=squeeze(app.modes.cpx(pIndex,tIndex,:));
                elseif strcmp(app.CompositionalDropDown.Value,'Garnet')
                    useData=squeeze(app.modes.gt(pIndex,tIndex,:));
                elseif strcmp(app.CompositionalDropDown.Value,'Spinel')
                    useData=squeeze(app.modes.sp(pIndex,tIndex,:));
                end
                %now add constraint

                app.constraints=app.constraints&useData<=app.MaximumEditField.Value&useData>=app.MinimumEditField.Value;
            end
            % now let's find data 1, we'll need it regardless of whether
            % there is a 1-d or 2-d plot
            if strcmp(app.Data1XAxisDropDown.Value,'Vp')
                app.data1=squeeze(app.moduli.vp(pIndex,tIndex,:));
                app.UIAxes.XLabel.String = 'V_p [km s^{-1}]';
            elseif strcmp(app.Data1XAxisDropDown.Value,'Vs')
                app.data1=squeeze(app.moduli.vs(pIndex,tIndex,:));
                app.UIAxes.XLabel.String = 'V_s [km s^{-1}]';
            elseif strcmp(app.Data1XAxisDropDown.Value,'Vp/Vs')
                app.data1=squeeze(app.moduli.vp(pIndex,tIndex,:)./app.moduli.vs(pIndex,tIndex,:));
                app.UIAxes.XLabel.String = 'V_p/V_s';
            elseif strcmp(app.Data1XAxisDropDown.Value,'Bulk Modulus')
                app.data1=squeeze(app.moduli.bulkFinal(pIndex,tIndex,:));
                app.UIAxes.XLabel.String = 'Bulk Modulus [Pa]';
            elseif strcmp(app.Data1XAxisDropDown.Value,'Shear Modulus')
                app.data1=squeeze(app.moduli.shearFinal(pIndex,tIndex,:));
                app.UIAxes.XLabel.String = 'Shear Modulus [Pa]';
            elseif strcmp(app.Data1XAxisDropDown.Value,'Density')
                app.data1=squeeze(app.density.rockDensity(pIndex,tIndex,:));
                app.UIAxes.XLabel.String = 'Density [kg/m^3]';
            elseif strcmp(app.Data1XAxisDropDown.Value,'Al2O3')
                app.data1=app.compositions.Al2O3database';
                app.UIAxes.XLabel.String = 'Al_2O_3 [wt. %]';
            elseif strcmp(app.Data1XAxisDropDown.Value,'CaO')
                app.data1=app.compositions.CaOdatabase';
                app.UIAxes.XLabel.String = 'CaO [wt. %]';
            elseif strcmp(app.Data1XAxisDropDown.Value,'Cr2O3')
                app.data1=app.compositions.Cr2O3database';
                app.UIAxes.XLabel.String = 'Cr_2O_3 [wt. %]';
            elseif strcmp(app.Data1XAxisDropDown.Value,'FeO')
                app.data1=app.compositions.FeOTdatabase';
                app.UIAxes.XLabel.String = 'FeO [wt. %]';
            elseif strcmp(app.Data1XAxisDropDown.Value,'Mg #')
                app.data1=app.compositions.mgnum*100';
                app.UIAxes.XLabel.String = 'Mg #';
            elseif strcmp(app.Data1XAxisDropDown.Value,'MgO')
                app.data1=app.compositions.MgOdatabase';
                app.UIAxes.XLabel.String = 'MgO [wt. %]';
            elseif strcmp(app.Data1XAxisDropDown.Value,'Na2O')
                app.data1=app.compositions.Na2Odatabase';
                app.UIAxes.XLabel.String = 'Na_2O [wt. %]';
            elseif strcmp(app.Data1XAxisDropDown.Value,'SiO2')
                app.data1=app.compositions.SiO2database';
                app.UIAxes.XLabel.String = 'SiO_2 [wt. %]';
            elseif strcmp(app.Data1XAxisDropDown.Value,'Olivine')
                app.data1=squeeze(app.modes.ol(pIndex,tIndex,:));
                app.UIAxes.XLabel.String = 'Olivine [vol. %]';
            elseif strcmp(app.Data1XAxisDropDown.Value,'Orthopyroxene')
                app.data1=squeeze(app.modes.opx(pIndex,tIndex,:));
                app.UIAxes.XLabel.String = 'Orthopyroxene [vol. %]';
            elseif strcmp(app.Data1XAxisDropDown.Value,'Clinopyroxene')
                app.data1=squeeze(app.modes.cpx(pIndex,tIndex,:));
                app.UIAxes.XLabel.String = 'Clinopyroxene [vol. %]';
            elseif strcmp(app.Data1XAxisDropDown.Value,'Garnet')
                app.data1=squeeze(app.modes.gt(pIndex,tIndex,:));
                app.UIAxes.XLabel.String = 'Garnet [vol. %]';
            elseif strcmp(app.Data1XAxisDropDown.Value,'Spinel')
                app.data1=squeeze(app.modes.sp(pIndex,tIndex,:));
                app.UIAxes.XLabel.String = 'Spinel [vol. %]';
            end


            %then we check whether we need to do a 2-d plot or a histogram plot
            if app.DScatterButton_2.Value
                if strcmp(app.Data2YAxisDropDown.Value,'Vp')
                    app.data2=squeeze(app.moduli.vp(pIndex,tIndex,:));
                    app.UIAxes.YLabel.String = 'V_p [km s^{-1}]';
                elseif strcmp(app.Data2YAxisDropDown.Value,'Vs')
                    app.data2=squeeze(app.moduli.vs(pIndex,tIndex,:));
                    app.UIAxes.YLabel.String = 'V_s [km s^{-1}]';
                elseif strcmp(app.Data2YAxisDropDown.Value,'Vp/Vs')
                    app.data2=squeeze(app.moduli.vp(pIndex,tIndex,:)./app.moduli.vs(pIndex,tIndex,:));
                    app.UIAxes.YLabel.String = 'V_p/V_s';
                elseif strcmp(app.Data2YAxisDropDown.Value,'Bulk Modulus')
                    app.data2=squeeze(app.moduli.bulkFinal(pIndex,tIndex,:));
                    app.UIAxes.YLabel.String = 'Bulk Modulus [Pa]';
                elseif strcmp(app.Data2YAxisDropDown.Value,'Shear Modulus')
                    app.data2=squeeze(app.moduli.shearFinal(pIndex,tIndex,:));
                    app.UIAxes.YLabel.String = 'Shear Modulus [Pa]';
                elseif strcmp(app.Data2YAxisDropDown.Value,'Density')
                    app.data2=squeeze(app.density.rockDensity(pIndex,tIndex,:));
                    app.UIAxes.YLabel.String = 'Density [kg/m^3]';
                elseif strcmp(app.Data2YAxisDropDown.Value,'Al2O3')
                    app.data2=app.compositions.Al2O3database';
                    app.UIAxes.YLabel.String = 'Al_2O_3 [wt. %]';
                elseif strcmp(app.Data2YAxisDropDown.Value,'CaO')
                    app.data2=app.compositions.CaOdatabase';
                    app.UIAxes.YLabel.String = 'CaO [wt. %]';
                elseif strcmp(app.Data2YAxisDropDown.Value,'Cr2O3')
                    app.data2=app.compositions.Cr2O3database';
                    app.UIAxes.YLabel.String = 'Cr_2O_3 [wt. %]';
                elseif strcmp(app.Data2YAxisDropDown.Value,'FeO')
                    app.data2=app.compositions.FeOTdatabase';
                    app.UIAxes.YLabel.String = 'FeO [wt. %]';
                elseif strcmp(app.Data2YAxisDropDown.Value,'Mg #')
                    app.data2=app.compositions.mgnum*100';
                    app.UIAxes.YLabel.String = 'Mg #';
                elseif strcmp(app.Data2YAxisDropDown.Value,'MgO')
                    app.data2=app.compositions.MgOdatabase';
                    app.UIAxes.YLabel.String = 'MgO [wt. %]';
                elseif strcmp(app.Data2YAxisDropDown.Value,'Na2O')
                    app.data2=app.compositions.Na2Odatabase';
                    app.UIAxes.YLabel.String = 'Na_2O [wt. %]';
                elseif strcmp(app.Data2YAxisDropDown.Value,'SiO2')
                    app.data2=app.compositions.SiO2database';
                    app.UIAxes.YLabel.String = 'SiO_2 [wt. %]';
                elseif strcmp(app.Data2YAxisDropDown.Value,'Olivine')
                    app.data2=squeeze(app.modes.ol(pIndex,tIndex,:));
                    app.UIAxes.YLabel.String = 'Olivine [vol. %]';
                elseif strcmp(app.Data2YAxisDropDown.Value,'Orthopyroxene')
                    app.data2=squeeze(app.modes.opx(pIndex,tIndex,:));
                    app.UIAxes.YLabel.String = 'Orthopyroxene [vol. %]';
                elseif strcmp(app.Data2YAxisDropDown.Value,'Clinopyroxene')
                    app.data2=squeeze(app.modes.cpx(pIndex,tIndex,:));
                    app.UIAxes.YLabel.String = 'Clinopyroxene [vol. %]';
                elseif strcmp(app.Data2YAxisDropDown.Value,'Garnet')
                    app.data2=squeeze(app.modes.gt(pIndex,tIndex,:));
                    app.UIAxes.YLabel.String = 'Garnet [vol. %]';
                elseif strcmp(app.Data2YAxisDropDown.Value,'Spinel')
                    app.data2=squeeze(app.modes.sp(pIndex,tIndex,:));
                    app.UIAxes.YLabel.String = 'Spinel [vol. %]';
                end
                scatter(app.UIAxes,app.data1(app.constraints),app.data2(app.constraints),10,'Filled','MarkerFaceColor',[179, 239, 55]/256,'LineWidth',1,'MarkerEdgeColor','k');
            elseif app.HistogramButton_2.Value
                histogram(app.UIAxes,app.data1(app.constraints),'FaceColor',[179, 239, 55]/256,'LineWidth',1);
                app.UIAxes.YLabel.String = 'Number of Samples';
            end
            if app.SetAxesCheckBox.Value %if axes are specified, set them
                 app.UIAxes.XLim=[app.xAxisMin.Value app.xAxisMax.Value];
                 app.UIAxes.YLim=[app.yAxisMin.Value app.yAxisMax.Value];
            end
            app.rocksPlottedLabel.Text=['You are plotting ' num2str(sum(app.constraints)) ' rocks.'];
        end

        % Selection changed function: PlotTypeButtonGroup
        function PlotTypeButtonGroupSelectionChanged(app, event)
            %selectedButton = app.PlotTypeButtonGroup.SelectedObject;
            app.Data2YAxisDropDown.Visible=app.DScatterButton_2.Value;%if histogram is chosen, make data 2 invisible
            app.Data2Label.Visible=app.DScatterButton_2.Value;
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            checkedNodes = app.Tree.CheckedNodes;
            app.constraints=false(size(app.rocktype.rocktype));
            for i=1:length(checkedNodes)
                if ~isempty(checkedNodes(i).NodeData)
                    app.constraints=app.constraints|checkedNodes(i).NodeData==app.rocktype.rocktype;
                end
            end
        end

        % Value changed function: SetAxesCheckBox
        function SetAxesCheckBoxValueChanged(app, event)
            value = app.SetAxesCheckBox.Value;
            app.XLabel.Visible=value;
            app.YLabel.Visible=value;
            app.MinLabel.Visible=value;
            app.MaxLabel.Visible=value;
            app.yAxisMin.Visible=value;
            app.yAxisMax.Visible=value;
            app.xAxisMax.Visible=value;
            app.xAxisMin.Visible=value;
            if value
                app.UIAxes.XLimMode='manual';
                app.UIAxes.YLimMode='manual';
                 app.UIAxes.XLim=[app.xAxisMin.Value app.xAxisMax.Value];
                 app.UIAxes.YLim=[app.yAxisMin.Value app.yAxisMax.Value];
            else
                app.UIAxes.XLimMode='auto';
                app.UIAxes.YLimMode='auto';
            end

        end

        % Value changed function: xAxisMin
        function xAxisMinValueChanged(app, event)
            %value = app.xAxisMin.Value;
            app.UIAxes.XLim=[app.xAxisMin.Value app.xAxisMax.Value];
            app.UIAxes.YLim=[app.yAxisMin.Value app.yAxisMax.Value];
        end

        % Value changed function: xAxisMax
        function xAxisMaxValueChanged(app, event)
            app.UIAxes.XLim=[app.xAxisMin.Value app.xAxisMax.Value];
            app.UIAxes.YLim=[app.yAxisMin.Value app.yAxisMax.Value];
        end

        % Value changed function: yAxisMax
        function yAxisMaxValueChanged(app, event)
            app.UIAxes.XLim=[app.xAxisMin.Value app.xAxisMax.Value];
            app.UIAxes.YLim=[app.yAxisMin.Value app.yAxisMax.Value];
        end

        % Value changed function: yAxisMin
        function yAxisMinValueChanged(app, event)
            app.UIAxes.XLim=[app.xAxisMin.Value app.xAxisMax.Value];
            app.UIAxes.YLim=[app.yAxisMin.Value app.yAxisMax.Value];
        end

        % Button pushed function: ExportDatatoWorkspaceButton
        function ExportDatatoWorkspaceButtonPushed(app, event)
            %here label out the shit to call back.
            if app.DScatterButton_2.Value
                labels = {'X-Axis Data' ...
                    'Y-Axis Data' ...
                    'Rocks Used'};
                vars = {'xData','yData','useIndex'};
                values = {app.data1,app.data2,app.constraints};
            else
                labels = {'X-Axis Data' ...
                'Rocks Used'};
                vars = {'xData','useIndex'};
                values = {app.data1,app.constraints};
            end
            export2wsdlg(labels,vars,values);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot!';
            app.LoadSpeedFileButton = uibutton(app.UIFigure, 'push');
            app.LoadSpeedFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSpeedFileButtonPushed, true);
            app.LoadSpeedFileButton.Text = 'Load Speed File';
            app.Temperaturechar176CEditField = uieditfield(app.UIFigure, 'numeric');
            app.Temperaturechar176CEditField.Limits = [300 1600];
            app.Temperaturechar176CEditField.Value = 1000;
            app.PressureGPaEditFieldLabel = uilabel(app.UIFigure);
            app.PressureGPaEditFieldLabel.Text = 'Pressure [GPa]';
            app.PressureGPaEditField = uieditfield(app.UIFigure, 'numeric');
            app.PressureGPaEditField.Limits = [0.5 6];
            app.PressureGPaEditField.Value = 2;
            app.PlotTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.PlotTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.PlotTypeButtonGroup.Title = 'Plot Type';
            app.HistogramButton_2 = uiradiobutton(app.PlotTypeButtonGroup);
            app.HistogramButton_2.Text = 'Histogram';
            app.DScatterButton_2 = uiradiobutton(app.PlotTypeButtonGroup);
            app.DScatterButton_2.Text = '2D Scatter';
            app.DScatterButton_2.Value = true;
            app.DefaultfileisloadedLabel = uilabel(app.UIFigure);
            app.DefaultfileisloadedLabel.Text = 'Default file is loaded.';
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.PeridotitesNode = uitreenode(app.Tree);
            app.PeridotitesNode.Text = 'Peridotites';
            app.LherzolitesNode = uitreenode(app.PeridotitesNode);
            app.LherzolitesNode.NodeData = 4;
            app.LherzolitesNode.Text = 'Lherzolites';
            app.HarzburgitesNode = uitreenode(app.PeridotitesNode);
            app.HarzburgitesNode.NodeData = 2;
            app.HarzburgitesNode.Text = 'Harzburgites';
            app.WehrliteNode = uitreenode(app.PeridotitesNode);
            app.WehrliteNode.NodeData = 3;
            app.WehrliteNode.Text = 'Wehrlite';
            app.DunitesNode = uitreenode(app.PeridotitesNode);
            app.DunitesNode.NodeData = 1;
            app.DunitesNode.Text = 'Dunites';
            app.PyroxenitesNode = uitreenode(app.Tree);
            app.PyroxenitesNode.Text = 'Pyroxenites';
            app.OlivineWebsteriteNode = uitreenode(app.PyroxenitesNode);
            app.OlivineWebsteriteNode.NodeData = 5;
            app.OlivineWebsteriteNode.Text = 'Olivine Websterite';
            app.WebsteriteNode = uitreenode(app.PyroxenitesNode);
            app.WebsteriteNode.NodeData = 6;
            app.WebsteriteNode.Text = 'Websterite';
            app.ClinopyroxeniteNode = uitreenode(app.PyroxenitesNode);
            app.ClinopyroxeniteNode.NodeData = 8;
            app.ClinopyroxeniteNode.Text = 'Clinopyroxenite';
            app.OrthopyroxeniteNode = uitreenode(app.PyroxenitesNode);
            app.OrthopyroxeniteNode.NodeData = 7;
            app.OrthopyroxeniteNode.Text = 'Orthopyroxenite';
            app.OlivineOrthopyroxeniteNode = uitreenode(app.PyroxenitesNode);
            app.OlivineOrthopyroxeniteNode.NodeData = 10;
            app.OlivineOrthopyroxeniteNode.Text = 'Olivine Orthopyroxenite';
            app.OlivineClinopyroxeniteNode = uitreenode(app.PyroxenitesNode);
            app.OlivineClinopyroxeniteNode.NodeData = 11;
            app.OlivineClinopyroxeniteNode.Text = 'Olivine Clinopyroxenite';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.LherzolitesNode, app.HarzburgitesNode, app.WehrliteNode, app.DunitesNode, app.OlivineWebsteriteNode, app.WebsteriteNode, app.ClinopyroxeniteNode, app.OrthopyroxeniteNode, app.OlivineOrthopyroxeniteNode, app.OlivineClinopyroxeniteNode, app.PeridotitesNode, app.PyroxenitesNode];
            app.Data1XAxisDropDownLabel = uilabel(app.UIFigure);
            app.Data1XAxisDropDownLabel.Text = 'Data 1 (X-Axis)';
            app.Data1XAxisDropDown = uidropdown(app.UIFigure);
            app.Data1XAxisDropDown.Items = {'Vp', 'Vs', 'Vp/Vs', 'Density', 'Bulk Modulus', 'Shear Modulus', 'Al2O3', 'CaO', 'Cr2O3', 'FeO', 'MgO', 'Mg #', 'NaO', 'SiO2', 'Olivine', 'Orthopyroxene', 'Clinopyroxene', 'Garnet', 'Spinel', 'Plagioclase'};
            app.Data1XAxisDropDown.Value = 'Vp';
            app.Data2YAxisDropDown = uidropdown(app.UIFigure);
            app.Data2YAxisDropDown.Items = {'Vp', 'Vs', 'Vp/Vs', 'Density', 'Bulk Modulus', 'Shear Modulus', 'Al2O3', 'CaO', 'Cr2O3', 'FeO', 'MgO', 'Mg #', 'NaO', 'SiO2', 'Olivine', 'Orthopyroxene', 'Clinopyroxene', 'Garnet', 'Spinel', 'Plagioclase'};
            app.Data2YAxisDropDown.Value = 'Vp/Vs';
            app.RockTypestoPlotLabel = uilabel(app.UIFigure);
            app.RockTypestoPlotLabel.Text = 'Rock Types to Plot';
            app.CompositionalDropDown = uidropdown(app.UIFigure);
            app.CompositionalDropDown.Items = {'Al2O3', 'CaO', 'Cr2O3', 'FeO', 'MgO', 'Mg #', 'Na2O', 'SiO2', 'Olivine', 'Orthopyroxene', 'Clinopyroxene', 'Garnet', 'Spinel'};
            app.CompositionalDropDown.Visible = 'off';
            app.CompositionalDropDown.Value = 'Al2O3';
            app.AddaCompositionalConstraintCheckBox = uicheckbox(app.UIFigure);
            app.AddaCompositionalConstraintCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddaCompositionalConstraintCheckBoxValueChanged, true);
            app.AddaCompositionalConstraintCheckBox.Text = 'Add a Compositional Constraint';
            app.MinimumEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumEditField.Limits = [0 100];
            app.MinimumEditField.Visible = 'off';
            app.MaximumEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumEditField.Limits = [0 100];
            app.MaximumEditField.Visible = 'off';
            app.MaximumEditField.Value = 100;
            app.MinimumLabel = uilabel(app.UIFigure);
            app.MinimumLabel.Visible = 'off';
            app.MinimumLabel.Text = 'Minimum';
            app.MaximumLabel = uilabel(app.UIFigure);
            app.MaximumLabel.Visible = 'off';
            app.MaximumLabel.Text = 'Maximum';
            app.rocksPlottedLabel = uilabel(app.UIFigure);
            app.rocksPlottedLabel.Text = 'This label shows how many rocks are plotted.';
            app.Data2Label = uilabel(app.UIFigure);
            app.Data2Label.Text = 'Data 2 (Y-Axis)';
            app.TemperatureLabel = uilabel(app.UIFigure);
            app.TemperatureLabel.Text = 'Temperature';
            app.SetAxesCheckBox = uicheckbox(app.UIFigure);
            app.SetAxesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SetAxesCheckBoxValueChanged, true);
            app.SetAxesCheckBox.Text = 'Set Axes';
            app.xAxisMin = uieditfield(app.UIFigure, 'numeric');
            app.xAxisMin.ValueChangedFcn = createCallbackFcn(app, @xAxisMinValueChanged, true);
            app.xAxisMin.Visible = 'off';
            app.xAxisMin.Value = 7;
            app.xAxisMax = uieditfield(app.UIFigure, 'numeric');
            app.xAxisMax.ValueChangedFcn = createCallbackFcn(app, @xAxisMaxValueChanged, true);
            app.xAxisMax.Visible = 'off';
            app.xAxisMax.Value = 9;
            app.yAxisMax = uieditfield(app.UIFigure, 'numeric');
            app.yAxisMax.ValueChangedFcn = createCallbackFcn(app, @yAxisMaxValueChanged, true);
            app.yAxisMax.Visible = 'off';
            app.yAxisMax.Value = 1.9;
            app.yAxisMin = uieditfield(app.UIFigure, 'numeric');
            app.yAxisMin.ValueChangedFcn = createCallbackFcn(app, @yAxisMinValueChanged, true);
            app.yAxisMin.Visible = 'off';
            app.yAxisMin.Value = 1.7;
            app.MinLabel = uilabel(app.UIFigure);
            app.MinLabel.Visible = 'off';
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.UIFigure);
            app.MaxLabel.Visible = 'off';
            app.MaxLabel.Text = 'Max';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Visible = 'off';
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Visible = 'off';
            app.YLabel.Text = 'Y';
            app.ExportDatatoWorkspaceButton = uibutton(app.UIFigure, 'push');
            app.ExportDatatoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDatatoWorkspaceButtonPushed, true);
            app.ExportDatatoWorkspaceButton.Text = 'Export Data to Workspace';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WISTFUL_relations

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end